classdef Module1GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Module1UIFigure               matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        StepButtonGrid                matlab.ui.container.GridLayout
        GridLayout4                   matlab.ui.container.GridLayout
        Step1Button                   matlab.ui.control.Button
        Step2Button                   matlab.ui.control.Button
        MainViewsGrid                 matlab.ui.container.GridLayout
        taskPanel                     matlab.ui.container.GridLayout
        ImportPanel                   matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        GridLayout6                   matlab.ui.container.GridLayout
        OpenSTLButton                 matlab.ui.control.Button
        ClearSTLButton                matlab.ui.control.Button
        SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel  matlab.ui.control.Label
        SelectedFileNameLabel         matlab.ui.control.Label
        DetailsLabel                  matlab.ui.control.Label
        GotoStep2TransformButton      matlab.ui.control.Button
        previewAxis                   matlab.ui.control.UIAxes
        TransformPanel                matlab.ui.container.Panel
        TransformPanelGridLayout      matlab.ui.container.GridLayout
        RotationGridPanel             matlab.ui.container.GridLayout
        RotateHeader                  matlab.ui.container.GridLayout
        Image                         matlab.ui.control.Image
        RotateLabel_2                 matlab.ui.control.Label
        RotatethisModelCheckBox       matlab.ui.control.CheckBox
        RotationSpinnerPanel          matlab.ui.container.Panel
        RotatexdegreesSpinnerLabel    matlab.ui.control.Label
        RotatexdegreesSpinner         matlab.ui.control.Spinner
        RotateydegreesSpinnerLabel    matlab.ui.control.Label
        RotateydegreesSpinner         matlab.ui.control.Spinner
        RotatezdegreesSpinnerLabel    matlab.ui.control.Label
        RotatezdegreesSpinner         matlab.ui.control.Spinner
        ClearTransformsButton         matlab.ui.control.Button
        ApplyTransformstoModelButton  matlab.ui.control.Button
        GridLayout9                   matlab.ui.container.GridLayout
        TransformHelpButton           matlab.ui.control.Button
        SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel_2  matlab.ui.control.Label
        TranslationGridPanel          matlab.ui.container.GridLayout
        TranslateHeader               matlab.ui.container.GridLayout
        TranslateMoveLabel            matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        TranslatethisModelCheckBox    matlab.ui.control.CheckBox
        TranslationSpinnerPanel       matlab.ui.container.Panel
        TranslatexmmSpinnerLabel      matlab.ui.control.Label
        TranslateXSpinner             matlab.ui.control.Spinner
        TranslateymmSpinnerLabel      matlab.ui.control.Label
        TranslateYSpinner             matlab.ui.control.Spinner
        TranslatezmmSpinnerLabel      matlab.ui.control.Label
        TranslateZSpinner             matlab.ui.control.Spinner
        GridLayout10                  matlab.ui.container.GridLayout
        AutomaticallyplaceonPositivePlaneCheckBox  matlab.ui.control.CheckBox
        GridLayout11                  matlab.ui.container.GridLayout
        Image3                        matlab.ui.control.Image
        AutoPlaceLabel                matlab.ui.control.Label
        GridLayout12                  matlab.ui.container.GridLayout
        GridLayout13                  matlab.ui.container.GridLayout
        Image4                        matlab.ui.control.Image
        ScaleResizeLabel              matlab.ui.control.Label
        ScaleSpinnerPanel             matlab.ui.container.Panel
        ScaleLabel_2                  matlab.ui.control.Label
        ScaleSpinner                  matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        
        file                        % The STL File opened by the application
        importedSTLModel            % A copy of the model to restore if modification made in the transpose tab are reset
        stlModel                    % The Triangulated Model stored by MATLAB
        layerThickness = 0.2;       % Layer thickness defined by user (defaults to 0.2mm)
        
        uniformSliceGenerator;
        adaptiveSliceGenerator;
        
        slicerPath = [];            % The Slicer Path generated by the generators
        plotLimits;
        
    end
    
    methods (Access = private)
        
        % Updates the Plot with the triangulation from the STL File
        function updateImportPreview(app)
            
            % Clear plot first
            cla(app.previewAxis);
            
            % Then, draw
            view(app.previewAxis,3);
            patch(app.previewAxis, 'Faces', app.stlModel.ConnectivityList, 'Vertices', app.stlModel.Points, 'EdgeColor','black','FaceColor','none');
            
        end
        
        % Update the Plot with a preview of the slice paths for the print
        function updateSlicerPathPreview(app, maxLayer)
            
            % Clear plot first
            cla(app.previewAxis);

            % Get all z-Heights from output Slicer Data
            [~, zHeights] = groupcounts(app.slicerPath(:,3));
            
            % Set the maxLayer if input > 0, else default to maxHeight
            if maxLayer <= 0
                maxLayer = height(zHeights);
            end
            
            % Then, for each slice draw the path(s)
            for i = 1:maxLayer
    
                % Determine applicable z-axis slicer paths
                currZ = zHeights(i);
                zPlotPoints = app.slicerPath(app.slicerPath(:,3) == currZ,:);
                
                % Draw each path separately
                for j = 1:max(zPlotPoints(:,4))
                    pathPlotPoints = zPlotPoints(zPlotPoints(:,4) == j,:);
                    plot3(app.previewAxis,pathPlotPoints(:,1), pathPlotPoints(:,2), pathPlotPoints(:,3),'-b','LineWidth',1);
                    hold(app.previewAxis, 'on');
                end
            
            end
            
            % Set limits for plot
            xlim(app.previewAxis, app.plotLimits(1,:));
            ylim(app.previewAxis, app.plotLimits(2,:));
            zlim(app.previewAxis, app.plotLimits(3,:));
            
            % Disable the hold on the axis
            hold(app.previewAxis, 'off');
          
        end
        
        % Update the Step Button Controls at the top of the window based on
        % the steps currently available
        function updateStepControls(app, stepNumber)
            app.Step1Button.Enable = (stepNumber >= 1);
            app.Step2Button.Enable = (stepNumber >= 2);
        end
    
        
        function updateTransformMasterButtons(app, event)
            
            rotateChecked = app.RotatethisModelCheckBox.Value;
            transformChecked = app.TranslatethisModelCheckBox.Value;
            autoPlaceChecked = app.AutomaticallyplaceonPositivePlaneCheckBox.Value;
            scaleChanged = (app.ScaleSpinner.Value ~= 100);
            
            % If any of the checkboxes are checked, then enable the apply
            % button
            if (rotateChecked || transformChecked || autoPlaceChecked || scaleChanged)
                
                app.ApplyTransformstoModelButton.Enable = true;
                
            % If none of the checkboxes are checked, then reset model and
            % disable    
            else
                
                app.ApplyTransformstoModelButton.Enable = false;
                app.ClearTransformsButton.Enable = false;
                                
                ClearTransformsButtonPushed(app, event);
                
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenSTLButton
        function OpenSTLButtonPushed(app, event)
            
            % Show progress bar
            progressBar = uiprogressdlg(app.Module1UIFigure, 'Title','Please Wait','Message','Waiting for user file selection...','Indeterminate','on');
            
            try
                % Attempt to open the STL File
                fileTypes = {'*.stl', 'STL Files (.stl)'};
                [app.file, path] = uigetfile(fileTypes, 'Open STL File...');
                
                % Add PATH to file to MATLAB's path
                addpath(path);
                
                % Open the STL File and convert to triangulation data
                progressBar.Message = "Opening STL file";
%                 app.stlModel = stlread(app.file);
                app.stlModel = importSTL(app.file);
                app.importedSTLModel = app.stlModel;
                
                % Update the model displayed
                progressBar.Message = "Computing Data";
                app.previewAxis.Visible = true;
                updateImportPreview(app);
                
                % Update Labels
                app.SelectedFileNameLabel.Text = app.file;
                details = sprintf('%s\n%s',"Verticies: " + height(app.stlModel.Points),"Elements: " + height(app.stlModel.ConnectivityList));
                app.DetailsLabel.Text = details;
                
                % Update the Clear and Next buttons
                app.ClearSTLButton.Enable = true;
                app.OpenSTLButton.Enable = false;
                app.GotoStep2TransformButton.Enable = true;
                
                % Update the Step Control Buttons
                app.updateStepControls(4);
                
            catch exception
                % Ignore Exception and return
                errordlg(exception.message)
                close(progressBar);
                return
            end
            
            % Close progress bar
            close(progressBar);
            
        end

        % Button pushed function: ClearSTLButton
        function ClearSTLButtonPushed(app, event)
            
            % Clear the references
            app.stlModel = [];
            app.file = [];
            app.slicerPath = [];
            
            % Reset the Selected File Label
            app.SelectedFileNameLabel.Text = "No File Selected";
            
             % Update Labels
            app.DetailsLabel.Text = ' ';
            
            % Update the Clear and Next buttons
            app.ClearSTLButton.Enable = false;
            app.OpenSTLButton.Enable = true;
            app.GotoStep2TransformButton.Enable = false;
            
            % Reset the Step Control Buttons
            app.updateStepControls(1);
            
            % Reset the model and hide the figure
            app.previewAxis.Visible = false;
            cla(app.previewAxis);
                        
        end

        % Button pushed function: Step1Button
        function Step1ButtonPushed(app, event)
            
            % If moving from the Slicer View, update Preview with STL
%             if (app.SlicePanel.Parent == app.taskPanel)
%                 cla(app.previewAxis);
%                 app.updateImportPreview();
%             end
            
            app.ImportPanel.Parent = app.taskPanel;
            app.TransformPanel.Parent = [];
%             app.ConfigurePanel.Parent = [];
%             app.SlicePanel.Parent = [];
        end

        % Button pushed function: Step2Button
        function Step2ButtonPushed(app, event)
            
            % If moving from the Slicer View, update Preview with STL
%             if (app.SlicePanel.Parent == app.taskPanel)
%                 cla(app.previewAxis);
%                 app.updateImportPreview();
%             end
            
            app.ImportPanel.Parent = [];
            app.TransformPanel.Parent = app.taskPanel;
%             app.ConfigurePanel.Parent = [];
%             app.SlicePanel.Parent = [];
        end

        % Callback function
        function Step3ButtonPushed(app, event)
            
            % If moving from the Slicer View, update Preview with STL
            if (app.SlicePanel.Parent == app.taskPanel)
                cla(app.previewAxis);
                app.updateImportPreview();
            end
            
            app.ImportPanel.Parent = [];
            app.TransformPanel.Parent = [];
            app.ConfigurePanel.Parent = app.taskPanel;
            app.SlicePanel.Parent = [];
        end

        % Callback function
        function Step4ButtonPushed(app, event)
            
            app.ImportPanel.Parent = [];
            app.TransformPanel.Parent = [];
            app.ConfigurePanel.Parent = [];
            app.SlicePanel.Parent = app.taskPanel;
            maxZ = app.LayerViewSlider.Value;
            
            % If a slicer path is available, show it in the preview.
            % Otherwise, clear
            if height(app.slicerPath) == 0
                cla(app.previewAxis);
            else
                app.updateSlicerPathPreview(maxZ);
            end
            
        end

        % Button pushed function: GotoStep2TransformButton
        function GotoStep2TransformButtonPushed(app, event)
            Step2ButtonPushed(app,event);
        end

        % Callback function
        function GotoStep3ConfigureButtonPushed(app, event)
            Step3ButtonPushed(app,event);
        end

        % Callback function
        function GotoStep4SliceButtonPushed(app, event)
            Step4ButtonPushed(app,event);
        end

        % Callback function
        function LayerThicknessmmSpinnerValueChanged(app, event)
            app.layerThickness = app.LayerThicknessmmSpinner.Value;
        end

        % Callback function
        function SliceModelButtonPushed(app, event)
            
            % Show progress bar
            progressBar = uiprogressdlg(app.Module1UIFigure, 'Title','Please Wait','Message','Slicing (this might take a moment)','Indeterminate','on');
            
            % If using a uniform slicer, then create the new object and
            % slice
            if (app.useAdaptiveSlicingCheckBox.Value == 0)
                app.uniformSliceGenerator = FlatUniformSliceGenerator(app.stlModel, app.layerThickness);
                app.adaptiveSliceGenerator = [];
                app.slicerPath = app.uniformSliceGenerator.getSlicePath();
                
            else
                app.uniformSliceGenerator = [];
                app.adaptiveSliceGenerator = FlatAdaptiveSliceGenerator(app.stlModel, app.layerThickness);
                app.slicerPath = app.adaptiveSliceGenerator.getSlicePath();
            end
            
            % Update the plot with a preview of the slicer paths
            progressBar.Message = "Drawing slicer path preview (this might take a moment)";
            app.plotLimits = [min(app.slicerPath(:,1)), max(app.slicerPath(:,1));
                                min(app.slicerPath(:,2)), max(app.slicerPath(:,2));
                                min(app.slicerPath(:,3)), max(app.slicerPath(:,3))];
            updateSlicerPathPreview(app, -1);
            
            % Update the Slicer Preview Bar (with a pause for updating the
            % UI)
            progressBar.Message = "Updating UI (this might take a moment)";
            pause(1);
            [~, zHeights] = groupcounts(app.slicerPath(:,3));
            maxLayer = height(zHeights);
            app.LayerViewSlider.Limits = [1, maxLayer];
            app.LayerViewSlider.Value = maxLayer;
            app.LayerViewNumberLabel.Text = string(maxLayer);
            
            % Close progress bar
            close(progressBar);
            
        end

        % Button pushed function: ApplyTransformstoModelButton
        function ApplyTransformstoModelButtonPushed(app, event)
            
            % Show progress bar
            progressBar = uiprogressdlg(app.Module1UIFigure, 'Title','Please Wait','Message','Applying transforms','Indeterminate','on');
            
            % Retrieve the Spinner Values
            thetaX = app.RotatexdegreesSpinner.Value;
            thetaY = app.RotateydegreesSpinner.Value;
            thetaZ = app.RotatezdegreesSpinner.Value;
            
            dX = app.TranslateXSpinner.Value;
            dY = app.TranslateYSpinner.Value;
            dZ = app.TranslateZSpinner.Value;
            
            scale = app.ScaleSpinner.Value;
            
            % Retrive the Auto-place value
            autoPlace = app.AutomaticallyplaceonPositivePlaneCheckBox.Value;
            
            % Pass to function
            app.stlModel = stlTransform(app.importedSTLModel, thetaX, thetaY, thetaZ, autoPlace, dX, dY, dZ, scale);
            
            % Update plot
            app.updateImportPreview();
            
            % Enable the reset button
            app.ClearTransformsButton.Enable = true;
            
            % Close progress bar
            close(progressBar);
            
        end

        % Button pushed function: ClearTransformsButton
        function ClearTransformsButtonPushed(app, event)
            
            % Show progress bar
            progressBar = uiprogressdlg(app.Module1UIFigure, 'Title','Please Wait','Message','Reverting back to imported model','Indeterminate','on');
            
            % Reset to intial model
            app.stlModel = app.importedSTLModel;
            
            % Update Plot
            app.updateImportPreview();
            
            % Disable the reset button
            app.ClearTransformsButton.Enable = false;
            
            % Clear all checkboxes
            app.RotatethisModelCheckBox.Value = 0;
            app.TranslatethisModelCheckBox.Value = 0;
            app.AutomaticallyplaceonPositivePlaneCheckBox.Value = 0;
            app.AutomaticallyplaceonPositivePlaneCheckBox.Enable = 1;
            app.TranslatethisModelCheckBox.Enable = 1;
            
            % Clear all spinners
            app.RotatexdegreesSpinner.Enable = false;
            app.RotateydegreesSpinner.Enable = false;
            app.RotatezdegreesSpinner.Enable = false;
            
            app.RotatexdegreesSpinner.Value = 0;
            app.RotateydegreesSpinner.Value = 0;
            app.RotatezdegreesSpinner.Value = 0;
            
            app.TranslateXSpinner.Enable = false;
            app.TranslateYSpinner.Enable = false;
            app.TranslateZSpinner.Enable = false;
            
            app.TranslateXSpinner.Value = 0;
            app.TranslateYSpinner.Value = 0;
            app.TranslateZSpinner.Value = 0;
            
            app.ScaleSpinner.Value = 100.0;
            
            % Disable the Apply Button
            app.ApplyTransformstoModelButton.Enable = false;

            % Close progress bar
            close(progressBar);
            
        end

        % Value changed function: RotatethisModelCheckBox
        function RotatethisModelCheckBoxValueChanged(app, event)
            value = app.RotatethisModelCheckBox.Value;
            
            if value == 0
                
                app.RotatexdegreesSpinner.Enable = false;
                app.RotateydegreesSpinner.Enable = false;
                app.RotatezdegreesSpinner.Enable = false;
                
                app.RotatexdegreesSpinner.Value = 0;
                app.RotateydegreesSpinner.Value = 0;
                app.RotatezdegreesSpinner.Value = 0;
                
            else
                
                app.RotatexdegreesSpinner.Enable = true;
                app.RotateydegreesSpinner.Enable = true;
                app.RotatezdegreesSpinner.Enable = true;
                
            end
            
            updateTransformMasterButtons(app, event);
            
        end

        % Value changed function: TranslatethisModelCheckBox
        function TranslatethisModelCheckBoxValueChanged(app, event)
            value = app.TranslatethisModelCheckBox.Value;
            
            if value == 0
                
                app.TranslateXSpinner.Enable = false;
                app.TranslateYSpinner.Enable = false;
                app.TranslateZSpinner.Enable = false;
                
                app.TranslateXSpinner.Value = 0;
                app.TranslateYSpinner.Value = 0;
                app.TranslateZSpinner.Value = 0;
                
                app.AutomaticallyplaceonPositivePlaneCheckBox.Enable = true;
                
            else
                
                app.TranslateXSpinner.Enable = true;
                app.TranslateYSpinner.Enable = true;
                app.TranslateZSpinner.Enable = true;
                
                app.AutomaticallyplaceonPositivePlaneCheckBox.Value = 0;
                app.AutomaticallyplaceonPositivePlaneCheckBox.Enable = false;
                
            end
            
            updateTransformMasterButtons(app, event);
        end

        % Value changed function: 
        % AutomaticallyplaceonPositivePlaneCheckBox
        function AutomaticallyplaceonPositivePlaneCheckBoxValueChanged(app, event)
            value = app.AutomaticallyplaceonPositivePlaneCheckBox.Value;
            
            if value == 0
                app.TranslatethisModelCheckBox.Enable = true;
            else
                app.TranslatethisModelCheckBox.Value = 0;
                app.TranslatethisModelCheckBox.Enable = false;
            end
            
            updateTransformMasterButtons(app, event);
        end

        % Value changed function: ScaleSpinner
        function ScaleSpinnerValueChanged(app, event)
            updateTransformMasterButtons(app, event);
        end

        % Callback function
        function LayerViewSliderValueChanged(app, event)
            
            % Disable the slider
            app.LayerViewSlider.Enable = false;
            
            % Show progress bar
            progressBar = uiprogressdlg(app.Module1UIFigure, 'Title','Please Wait','Message','Updating slicer path preview (this might take a moment)','Indeterminate','on');
                        
            % Get the value and re-draw
            value = round(app.LayerViewSlider.Value);
            updateSlicerPathPreview(app, value);
            
            % Close Progress Bar
            pause(1);
            close(progressBar);
            
            % Re-enable the slider
            app.LayerViewSlider.Enable = true;
            
        end

        % Callback function
        function LayerViewSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.LayerViewNumberLabel.Text = string(changingValue);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Module1UIFigure = uifigure('Visible', 'off');
            app.Module1UIFigure.Name = 'Module 1: Importing STL and Viewing';
            app.Module1UIFigure.Icon = 'img_slice_32px.png';
            app.GridLayout = uigridlayout(app.Module1UIFigure);
            app.StepButtonGrid = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.StepButtonGrid);
            app.Step1Button = uibutton(app.GridLayout4, 'push');
            app.Step1Button.ButtonPushedFcn = createCallbackFcn(app, @Step1ButtonPushed, true);
            app.Step1Button.Icon = 'img_importSTL_32px.png';
            app.Step1Button.Text = '1. Import';
            app.Step2Button = uibutton(app.GridLayout4, 'push');
            app.Step2Button.ButtonPushedFcn = createCallbackFcn(app, @Step2ButtonPushed, true);
            app.Step2Button.Icon = 'img_transform_32px.png';
            app.Step2Button.Enable = 'off';
            app.Step2Button.Text = '2. Transform';
            app.MainViewsGrid = uigridlayout(app.GridLayout);
            app.taskPanel = uigridlayout(app.MainViewsGrid);
            app.ImportPanel = uipanel(app.taskPanel);
            app.ImportPanel.Title = 'Import';
            app.GridLayout5 = uigridlayout(app.ImportPanel);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.OpenSTLButton = uibutton(app.GridLayout6, 'push');
            app.OpenSTLButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSTLButtonPushed, true);
            app.OpenSTLButton.Icon = 'img_open_22px.png';
            app.OpenSTLButton.Text = 'Open STL...';
            app.ClearSTLButton = uibutton(app.GridLayout6, 'push');
            app.ClearSTLButton.ButtonPushedFcn = createCallbackFcn(app, @ClearSTLButtonPushed, true);
            app.ClearSTLButton.Icon = 'img_erase_22px.png';
            app.ClearSTLButton.Enable = 'off';
            app.ClearSTLButton.Text = 'Clear STL...';
            app.SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel = uilabel(app.GridLayout5);
            app.SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel.Text = 'Select "Open STL..." and browse to the STL file to be imported & sliced:';
            app.SelectedFileNameLabel = uilabel(app.GridLayout5);
            app.SelectedFileNameLabel.Text = 'No File Selected';
            app.DetailsLabel = uilabel(app.GridLayout5);
            app.DetailsLabel.Text = ' ';
            app.GotoStep2TransformButton = uibutton(app.GridLayout5, 'push');
            app.GotoStep2TransformButton.ButtonPushedFcn = createCallbackFcn(app, @GotoStep2TransformButtonPushed, true);
            app.GotoStep2TransformButton.Icon = 'img_arrowRight_16px.png';
            app.GotoStep2TransformButton.Enable = 'off';
            app.GotoStep2TransformButton.Text = 'Go to Step 2: Transform';
            app.previewAxis = uiaxes(app.MainViewsGrid);
            xlabel(app.previewAxis, 'X')
            ylabel(app.previewAxis, 'Y')
            zlabel(app.previewAxis, 'Z')
            app.previewAxis.Visible = 'off';
            app.TransformPanel = uipanel(app.Module1UIFigure);
            app.TransformPanel.Title = 'Transform';
            app.TransformPanelGridLayout = uigridlayout(app.TransformPanel);
            app.RotationGridPanel = uigridlayout(app.TransformPanelGridLayout);
            app.RotateHeader = uigridlayout(app.RotationGridPanel);
            app.Image = uiimage(app.RotateHeader);
            app.Image.ImageSource = 'img_transformRotate_22px.png';
            app.RotateLabel_2 = uilabel(app.RotateHeader);
            app.RotateLabel_2.Text = 'Rotate';
            app.RotatethisModelCheckBox = uicheckbox(app.RotationGridPanel);
            app.RotatethisModelCheckBox.ValueChangedFcn = createCallbackFcn(app, @RotatethisModelCheckBoxValueChanged, true);
            app.RotatethisModelCheckBox.Text = 'Rotate this Model';
            app.RotationSpinnerPanel = uipanel(app.RotationGridPanel);
            app.RotatexdegreesSpinnerLabel = uilabel(app.RotationSpinnerPanel);
            app.RotatexdegreesSpinnerLabel.Text = 'Rotate x (degrees)';
            app.RotatexdegreesSpinner = uispinner(app.RotationSpinnerPanel);
            app.RotatexdegreesSpinner.Step = 0.1;
            app.RotatexdegreesSpinner.Limits = [-360 360];
            app.RotatexdegreesSpinner.Enable = 'off';
            app.RotateydegreesSpinnerLabel = uilabel(app.RotationSpinnerPanel);
            app.RotateydegreesSpinnerLabel.Text = 'Rotate y (degrees)';
            app.RotateydegreesSpinner = uispinner(app.RotationSpinnerPanel);
            app.RotateydegreesSpinner.Step = 0.1;
            app.RotateydegreesSpinner.Limits = [-360 360];
            app.RotateydegreesSpinner.Enable = 'off';
            app.RotatezdegreesSpinnerLabel = uilabel(app.RotationSpinnerPanel);
            app.RotatezdegreesSpinnerLabel.Text = 'Rotate z (degrees)';
            app.RotatezdegreesSpinner = uispinner(app.RotationSpinnerPanel);
            app.RotatezdegreesSpinner.Step = 0.1;
            app.RotatezdegreesSpinner.Limits = [-360 360];
            app.RotatezdegreesSpinner.Enable = 'off';
            app.ClearTransformsButton = uibutton(app.TransformPanelGridLayout, 'push');
            app.ClearTransformsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTransformsButtonPushed, true);
            app.ClearTransformsButton.Icon = 'img_erase_22px.png';
            app.ClearTransformsButton.Enable = 'off';
            app.ClearTransformsButton.Text = 'Clear Transforms';
            app.ApplyTransformstoModelButton = uibutton(app.TransformPanelGridLayout, 'push');
            app.ApplyTransformstoModelButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyTransformstoModelButtonPushed, true);
            app.ApplyTransformstoModelButton.Icon = 'img_transform_22px.png';
            app.ApplyTransformstoModelButton.Enable = 'off';
            app.ApplyTransformstoModelButton.Text = 'Apply Transforms to Model';
            app.GridLayout9 = uigridlayout(app.TransformPanelGridLayout);
            app.TransformHelpButton = uibutton(app.GridLayout9, 'push');
            app.TransformHelpButton.Icon = 'img_help_22px.png';
            app.TransformHelpButton.Text = '';
            app.SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel_2 = uilabel(app.GridLayout9);
            app.SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel_2.Text = 'Transform the model for slicing using the toolset below if necessary:';
            app.TranslationGridPanel = uigridlayout(app.TransformPanelGridLayout);
            app.TranslateHeader = uigridlayout(app.TranslationGridPanel);
            app.TranslateMoveLabel = uilabel(app.TranslateHeader);
            app.TranslateMoveLabel.Text = 'Translate (Move)';
            app.Image2 = uiimage(app.TranslateHeader);
            app.Image2.ImageSource = 'img_translate_22px.png';
            app.TranslatethisModelCheckBox = uicheckbox(app.TranslationGridPanel);
            app.TranslatethisModelCheckBox.ValueChangedFcn = createCallbackFcn(app, @TranslatethisModelCheckBoxValueChanged, true);
            app.TranslatethisModelCheckBox.Text = 'Translate this Model';
            app.TranslationSpinnerPanel = uipanel(app.TranslationGridPanel);
            app.TranslatexmmSpinnerLabel = uilabel(app.TranslationSpinnerPanel);
            app.TranslatexmmSpinnerLabel.Text = 'Translate x (mm)';
            app.TranslateXSpinner = uispinner(app.TranslationSpinnerPanel);
            app.TranslateXSpinner.Step = 0.1;
            app.TranslateXSpinner.Limits = [-360 360];
            app.TranslateXSpinner.Enable = 'off';
            app.TranslateymmSpinnerLabel = uilabel(app.TranslationSpinnerPanel);
            app.TranslateymmSpinnerLabel.Text = 'Translate y (mm)';
            app.TranslateYSpinner = uispinner(app.TranslationSpinnerPanel);
            app.TranslateYSpinner.Step = 0.1;
            app.TranslateYSpinner.Limits = [-360 360];
            app.TranslateYSpinner.Enable = 'off';
            app.TranslatezmmSpinnerLabel = uilabel(app.TranslationSpinnerPanel);
            app.TranslatezmmSpinnerLabel.Text = 'Translate z (mm)';
            app.TranslateZSpinner = uispinner(app.TranslationSpinnerPanel);
            app.TranslateZSpinner.Step = 0.1;
            app.TranslateZSpinner.Limits = [-360 360];
            app.TranslateZSpinner.Enable = 'off';
            app.GridLayout10 = uigridlayout(app.TransformPanelGridLayout);
            app.AutomaticallyplaceonPositivePlaneCheckBox = uicheckbox(app.GridLayout10);
            app.AutomaticallyplaceonPositivePlaneCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutomaticallyplaceonPositivePlaneCheckBoxValueChanged, true);
            app.AutomaticallyplaceonPositivePlaneCheckBox.Text = 'Automatically place on Positive Plane';
            app.GridLayout11 = uigridlayout(app.GridLayout10);
            app.Image3 = uiimage(app.GridLayout11);
            app.Image3.ImageSource = 'img_autoPlace_22px.png';
            app.AutoPlaceLabel = uilabel(app.GridLayout11);
            app.AutoPlaceLabel.Text = 'Auto-Place';
            app.GridLayout12 = uigridlayout(app.TransformPanelGridLayout);
            app.GridLayout13 = uigridlayout(app.GridLayout12);
            app.Image4 = uiimage(app.GridLayout13);
            app.Image4.ImageSource = 'img_scale_22px.png';
            app.ScaleResizeLabel = uilabel(app.GridLayout13);
            app.ScaleResizeLabel.Text = 'Scale (Resize)';
            app.ScaleSpinnerPanel = uipanel(app.GridLayout12);
            app.ScaleLabel_2 = uilabel(app.ScaleSpinnerPanel);
            app.ScaleLabel_2.Text = 'Scale (%)';
            app.ScaleSpinner = uispinner(app.ScaleSpinnerPanel);
            app.ScaleSpinner.Step = 0.1;
            app.ScaleSpinner.Limits = [0.1 Inf];
            app.ScaleSpinner.ValueChangedFcn = createCallbackFcn(app, @ScaleSpinnerValueChanged, true);
            app.ScaleSpinner.Value = 100;
            % Show the figure after all components are created
            app.Module1UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module1GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Module1UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Module1UIFigure)
        end
    end
end