classdef Module2GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Module2AdaptiveFlatLayerSlicerUIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        StepButtonGrid                matlab.ui.container.GridLayout
        GridLayout4                   matlab.ui.container.GridLayout
        Step1Button                   matlab.ui.control.Button
        Step2Button                   matlab.ui.control.Button
        Step3Button                   matlab.ui.control.Button
        Step4Button                   matlab.ui.control.Button
        MainViewsGrid                 matlab.ui.container.GridLayout
        taskPanel                     matlab.ui.container.GridLayout
        ImportPanel                   matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        GridLayout6                   matlab.ui.container.GridLayout
        OpenSTLButton                 matlab.ui.control.Button
        ClearSTLButton                matlab.ui.control.Button
        SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel  matlab.ui.control.Label
        SelectedFileNameLabel         matlab.ui.control.Label
        DetailsLabel                  matlab.ui.control.Label
        GotoStep2TransformButton      matlab.ui.control.Button
        previewAxis                   matlab.ui.control.UIAxes
        TransformPanel                matlab.ui.container.Panel
        GridLayout5_2                 matlab.ui.container.GridLayout
        SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel_2  matlab.ui.control.Label
        GotoStep3ConfigureButton      matlab.ui.control.Button
        RotationGridPanel             matlab.ui.container.GridLayout
        GridLayout8                   matlab.ui.container.GridLayout
        Image                         matlab.ui.control.Image
        RotationLabel                 matlab.ui.control.Label
        RotateLabel                   matlab.ui.control.Label
        RotatethisModelCheckBox       matlab.ui.control.CheckBox
        RotationSpinnerPanel          matlab.ui.container.Panel
        RotatexdegreesSpinnerLabel    matlab.ui.control.Label
        RotatexdegreesSpinner         matlab.ui.control.Spinner
        RotateydegreesSpinnerLabel    matlab.ui.control.Label
        RotateydegreesSpinner         matlab.ui.control.Spinner
        RotatezdegreesSpinnerLabel    matlab.ui.control.Label
        RotatezdegreesSpinner         matlab.ui.control.Spinner
        ClearTransformButton          matlab.ui.control.Button
        ApplyTransformtoModelButton   matlab.ui.control.Button
        ConfigurePanel                matlab.ui.container.Panel
        GridLayout5_3                 matlab.ui.container.GridLayout
        DetailsLabel_3                matlab.ui.control.Label
        GotoStep4SliceButton          matlab.ui.control.Button
        GridLayout7                   matlab.ui.container.GridLayout
        Label                         matlab.ui.control.Label
        useAdaptiveSlicingCheckBox    matlab.ui.control.CheckBox
        Panel                         matlab.ui.container.Panel
        LayerThicknessmmSpinnerLabel  matlab.ui.control.Label
        LayerThicknessmmSpinner       matlab.ui.control.Spinner
        SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel_5  matlab.ui.control.Label
        SlicePanel                    matlab.ui.container.Panel
        GridLayout5_4                 matlab.ui.container.GridLayout
        GridLayout6_4                 matlab.ui.container.GridLayout
        SliceButton                   matlab.ui.control.Button
        ClearSTLButton_4              matlab.ui.control.Button
        SlicerLabel                   matlab.ui.control.Label
        SelectedFileNameLabel_4       matlab.ui.control.Label
    end

    
    properties (Access = private)
        file                    % The STL File opened by the application
        importedSTLModel        % A copy of the model to restore if modification made in the transpose tab are reset
        stlModel                % The Triangulated Model stored by MATLAB
        layerThickness = 1;     % Layer thickness defined by user (defaults to 1mm)
        
        uniformSliceGenerator;
        adaptiveSliceGenerator;
        
        slicerPath = []; 
    end
    
    methods (Access = private)
        
        % Updates the Plot with the triangulation from the STL File
        function updateImportPreview(app)
            
            % Clear plot first
            cla(app.previewAxis);
            
            % Then, draw
            view(app.previewAxis,3);
            patch(app.previewAxis, 'Faces', app.stlModel.ConnectivityList, 'Vertices', app.stlModel.Points, 'EdgeColor','black','FaceColor','none');
            
        end
        
        % Update the Plot with a preview of the slice paths for the print
        function updateSlicerPathPreview(app)
            
            % Clear plot first
            cla(app.previewAxis);
            
            % Then, for each slice draw the path(s)
            for i = 0:app.layerThickness:double(max(app.slicerPath(:,3)))
    
                % Draw each path separately
                zPlotPoints = app.slicerPath(app.slicerPath(:,3) == i,:);
                for j = 1:max(zPlotPoints(:,4))
                    pathPlotPoints = zPlotPoints(zPlotPoints(:,4) == j,:);
                    plot3(app.previewAxis,pathPlotPoints(:,1), pathPlotPoints(:,2), pathPlotPoints(:,3),'-b','LineWidth',1);
                    hold(app.previewAxis, 'on');
                end
            
            end
            
            % Disable the hold on the axis
            hold(app.previewAxis, 'off');
          
        end
        
        % Update the Step Button Controls at the top of the window based on
        % the steps currently available
        function updateStepControls(app, stepNumber)
            app.Step1Button.Enable = (stepNumber >= 1);
            app.Step2Button.Enable = (stepNumber >= 2);
            app.Step3Button.Enable = (stepNumber >= 3);
            app.Step4Button.Enable = (stepNumber >= 4);
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenSTLButton
        function OpenSTLButtonPushed(app, event)
            
            % Show progress bar
            progressBar = uiprogressdlg(app.Module2AdaptiveFlatLayerSlicerUIFigure, 'Title','Please Wait','Message','Waiting for user file selection...','Indeterminate','on');
            
            try
                % Attempt to open the STL File
                fileTypes = {'*.stl', 'STL Files (.stl)'};
                [app.file, path] = uigetfile(fileTypes, 'Open STL File...');
                
                % Add PATH to file to MATLAB's path
                addpath(path);
                
                % Open the STL File and convert to triangulation data
                progressBar.Message = "Opening STL file";
                app.stlModel = stlread(app.file);
                app.importedSTLModel = app.stlModel;
                
                % Update the model displayed
                progressBar.Message = "Computing Data";
                app.previewAxis.Visible = true;
                updateImportPreview(app);
                
                % Update Labels
                app.SelectedFileNameLabel.Text = app.file;
                details = sprintf('%s\n%s',"Verticies: " + height(app.stlModel.Points),"Elements: " + height(app.stlModel.ConnectivityList));
                app.DetailsLabel.Text = details;
                
                % Update the Clear and Next buttons
                app.ClearSTLButton.Enable = true;
                app.OpenSTLButton.Enable = false;
                app.GotoStep2TransformButton.Enable = true;
                
                % Update the Step Control Buttons
                app.updateStepControls(4);
                
            catch exception
                % Ignore Exception and return
                errordlg(exception.message)
                close(progressBar);
                return
            end
            
            % Close progress bar
            close(progressBar);
            
        end

        % Button pushed function: ClearSTLButton
        function ClearSTLButtonPushed(app, event)
            
            % Clear the references
            app.stlModel = [];
            app.file = [];
            
            % Reset the Selected File Label
            app.SelectedFileNameLabel.Text = "No File Selected";
            
             % Update Labels
            app.DetailsLabel.Text = ' ';
            
            % Update the Clear and Next buttons
            app.ClearSTLButton.Enable = false;
            app.OpenSTLButton.Enable = true;
            app.GotoStep2TransformButton.Enable = false;
            
            % Reset the Step Control Buttons
            app.updateStepControls(1);
            
            % Reset the model and hide the figure
            app.previewAxis.Visible = false;
            cla(app.previewAxis);
                        
        end

        % Button pushed function: Step1Button
        function Step1ButtonPushed(app, event)
            
            % If moving from the Slicer View, update Preview with STL
            if (app.SlicePanel.Parent == app.taskPanel)
                cla(app.previewAxis);
                app.updateImportPreview();
            end
            
            app.ImportPanel.Parent = app.taskPanel;
            app.TransformPanel.Parent = [];
            app.ConfigurePanel.Parent = [];
            app.SlicePanel.Parent = [];
        end

        % Button pushed function: Step2Button
        function Step2ButtonPushed(app, event)
            
            % If moving from the Slicer View, update Preview with STL
            if (app.SlicePanel.Parent == app.taskPanel)
                cla(app.previewAxis);
                app.updateImportPreview();
            end
            
            app.ImportPanel.Parent = [];
            app.TransformPanel.Parent = app.taskPanel;
            app.ConfigurePanel.Parent = [];
            app.SlicePanel.Parent = [];
        end

        % Button pushed function: Step3Button
        function Step3ButtonPushed(app, event)
            
            % If moving from the Slicer View, update Preview with STL
            if (app.SlicePanel.Parent == app.taskPanel)
                cla(app.previewAxis);
                app.updateImportPreview();
            end
            
            app.ImportPanel.Parent = [];
            app.TransformPanel.Parent = [];
            app.ConfigurePanel.Parent = app.taskPanel;
            app.SlicePanel.Parent = [];
        end

        % Button pushed function: Step4Button
        function Step4ButtonPushed(app, event)
            
            app.ImportPanel.Parent = [];
            app.TransformPanel.Parent = [];
            app.ConfigurePanel.Parent = [];
            app.SlicePanel.Parent = app.taskPanel;
            
            % If a slicer path is available, show it in the preview.
            % Otherwise, clear
            if height(app.slicerPath) == 0
                cla(app.previewAxis);
            else
                app.updateSlicerPathPreview();
            end
            
        end

        % Button pushed function: GotoStep2TransformButton
        function GotoStep2TransformButtonPushed(app, event)
            Step2ButtonPushed(app,event);
        end

        % Button pushed function: GotoStep3ConfigureButton
        function GotoStep3ConfigureButtonPushed(app, event)
            Step3ButtonPushed(app,event);
        end

        % Button pushed function: GotoStep4SliceButton
        function GotoStep4SliceButtonPushed(app, event)
            Step4ButtonPushed(app,event);
        end

        % Value changed function: LayerThicknessmmSpinner
        function LayerThicknessmmSpinnerValueChanged(app, event)
            app.layerThickness = app.LayerThicknessmmSpinner.Value;
        end

        % Button pushed function: SliceButton
        function SliceButtonPushed(app, event)
            
            % Show progress bar
            progressBar = uiprogressdlg(app.Module2AdaptiveFlatLayerSlicerUIFigure, 'Title','Please Wait','Message','Slicing...','Indeterminate','on');
            
            % If using a uniform slicer, then create the new object and
            % slice
            if (app.useAdaptiveSlicingCheckBox.Value == 0)
                app.uniformSliceGenerator = FlatUniformSliceGenerator(app.stlModel, app.layerThickness);
                app.adaptiveSliceGenerator = [];
                app.slicerPath = app.uniformSliceGenerator.getSlicePath();
            end
            
            % Update the plot with a preview of the slicer paths
            updateSlicerPathPreview(app);
            
            % Close progress bar
            close(progressBar);
            
        end

        % Button pushed function: ApplyTransformtoModelButton
        function ApplyTransformtoModelButtonPushed(app, event)
            
            % Show progress bar
            progressBar = uiprogressdlg(app.Module2AdaptiveFlatLayerSlicerUIFigure, 'Title','Please Wait','Message','Applying transforms','Indeterminate','on');
            
            % Retrieve the Spinner Values
            thetaX = app.RotatexdegreesSpinner.Value;
            thetaY = app.RotateydegreesSpinner.Value;
            thetaZ = app.RotatezdegreesSpinner.Value;
            
            % Pass to function
            app.stlModel = stlTransform(app.importedSTLModel, thetaX, thetaY, thetaZ);
            
            % Update plot
            app.updateImportPreview();
            
            % Enable the reset button
            app.ClearTransformButton.Enable = true;
            
            % Close progress bar
            close(progressBar);
            
        end

        % Button pushed function: ClearTransformButton
        function ClearTransformButtonPushed(app, event)
            
            % Show progress bar
            progressBar = uiprogressdlg(app.Module2AdaptiveFlatLayerSlicerUIFigure, 'Title','Please Wait','Message','Reverting back to imported model','Indeterminate','on');
            
            % Reset to intial model
            app.stlModel = app.importedSTLModel;
            
            % Update Plot
            app.updateImportPreview();
            
            % Disable the reset button
            app.ClearTransformButton.Enable = false;

            % Close progress bar
            close(progressBar);
            
        end

        % Value changed function: RotatethisModelCheckBox
        function RotatethisModelCheckBoxValueChanged(app, event)
            value = app.RotatethisModelCheckBox.Value;
            
            if value == 0
                
                ClearTransformButtonPushed(app, event);
                
                app.ApplyTransformtoModelButton.Enable = false;
                app.ClearTransformButton.Enable = false;
                
                app.RotatexdegreesSpinner.Enable = false;
                app.RotateydegreesSpinner.Enable = false;
                app.RotatezdegreesSpinner.Enable = false;
                
                app.RotatexdegreesSpinner.Value = 0;
                app.RotateydegreesSpinner.Value = 0;
                app.RotatezdegreesSpinner.Value = 0;
                
            else
                
                app.ApplyTransformtoModelButton.Enable = true;
                
                app.RotatexdegreesSpinner.Enable = true;
                app.RotateydegreesSpinner.Enable = true;
                app.RotatezdegreesSpinner.Enable = true;
                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Module2AdaptiveFlatLayerSlicerUIFigure = uifigure('Visible', 'off');
            app.Module2AdaptiveFlatLayerSlicerUIFigure.Name = 'Module 2: Adaptive Flat Layer Slicer';
            app.Module2AdaptiveFlatLayerSlicerUIFigure.Icon = 'img_slice_32px.png';
            app.GridLayout = uigridlayout(app.Module2AdaptiveFlatLayerSlicerUIFigure);
            app.StepButtonGrid = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.StepButtonGrid);
            app.Step1Button = uibutton(app.GridLayout4, 'push');
            app.Step1Button.ButtonPushedFcn = createCallbackFcn(app, @Step1ButtonPushed, true);
            app.Step1Button.Icon = 'img_importSTL_32px.png';
            app.Step1Button.Text = '1. Import';
            app.Step2Button = uibutton(app.GridLayout4, 'push');
            app.Step2Button.ButtonPushedFcn = createCallbackFcn(app, @Step2ButtonPushed, true);
            app.Step2Button.Icon = 'img_transform_32px.png';
            app.Step2Button.Enable = 'off';
            app.Step2Button.Text = '2. Transform';
            app.Step3Button = uibutton(app.GridLayout4, 'push');
            app.Step3Button.ButtonPushedFcn = createCallbackFcn(app, @Step3ButtonPushed, true);
            app.Step3Button.Icon = 'img_configure_32px.png';
            app.Step3Button.Enable = 'off';
            app.Step3Button.Text = '3. Configure';
            app.Step4Button = uibutton(app.GridLayout4, 'push');
            app.Step4Button.ButtonPushedFcn = createCallbackFcn(app, @Step4ButtonPushed, true);
            app.Step4Button.Icon = 'img_slice_32px.png';
            app.Step4Button.Enable = 'off';
            app.Step4Button.Text = '4. Slice';
            app.MainViewsGrid = uigridlayout(app.GridLayout);
            app.taskPanel = uigridlayout(app.MainViewsGrid);
            app.ImportPanel = uipanel(app.taskPanel);
            app.ImportPanel.Title = 'Import';
            app.GridLayout5 = uigridlayout(app.ImportPanel);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.OpenSTLButton = uibutton(app.GridLayout6, 'push');
            app.OpenSTLButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSTLButtonPushed, true);
            app.OpenSTLButton.Icon = 'img_open_22px.png';
            app.OpenSTLButton.Text = 'Open STL...';
            app.ClearSTLButton = uibutton(app.GridLayout6, 'push');
            app.ClearSTLButton.ButtonPushedFcn = createCallbackFcn(app, @ClearSTLButtonPushed, true);
            app.ClearSTLButton.Icon = 'img_erase_22px.png';
            app.ClearSTLButton.Enable = 'off';
            app.ClearSTLButton.Text = 'Clear STL...';
            app.SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel = uilabel(app.GridLayout5);
            app.SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel.Text = 'Select "Open STL..." and browse to the STL file to be imported & sliced:';
            app.SelectedFileNameLabel = uilabel(app.GridLayout5);
            app.SelectedFileNameLabel.Text = 'No File Selected';
            app.DetailsLabel = uilabel(app.GridLayout5);
            app.DetailsLabel.Text = ' ';
            app.GotoStep2TransformButton = uibutton(app.GridLayout5, 'push');
            app.GotoStep2TransformButton.ButtonPushedFcn = createCallbackFcn(app, @GotoStep2TransformButtonPushed, true);
            app.GotoStep2TransformButton.Icon = 'img_arrowRight_16px.png';
            app.GotoStep2TransformButton.Enable = 'off';
            app.GotoStep2TransformButton.Text = 'Go to Step 2: Transform';
            app.previewAxis = uiaxes(app.MainViewsGrid);
            xlabel(app.previewAxis, 'X')
            ylabel(app.previewAxis, 'Y')
            zlabel(app.previewAxis, 'Z')
            app.previewAxis.Visible = 'off';
            app.TransformPanel = uipanel(app.Module2AdaptiveFlatLayerSlicerUIFigure);
            app.TransformPanel.Title = 'Transform';
            app.GridLayout5_2 = uigridlayout(app.TransformPanel);
            app.SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel_2 = uilabel(app.GridLayout5_2);
            app.SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel_2.Text = 'Use the Transform Controls below to modify the layout of the STL file if necessary:';
            app.GotoStep3ConfigureButton = uibutton(app.GridLayout5_2, 'push');
            app.GotoStep3ConfigureButton.ButtonPushedFcn = createCallbackFcn(app, @GotoStep3ConfigureButtonPushed, true);
            app.GotoStep3ConfigureButton.Icon = 'img_arrowRight_16px.png';
            app.GotoStep3ConfigureButton.Text = 'Go to Step 3: Configure';
            app.RotationGridPanel = uigridlayout(app.GridLayout5_2);
            app.GridLayout8 = uigridlayout(app.RotationGridPanel);
            app.Image = uiimage(app.GridLayout8);
            app.Image.ImageSource = 'img_transformRotate_22px.png';
            app.RotationLabel = uilabel(app.GridLayout8);
            app.RotationLabel.Text = 'Rotation';
            app.RotateLabel = uilabel(app.RotationGridPanel);
            app.RotateLabel.Text = 'Rotate the model around the x, y and z axis to prepare the model before slicing.';
            app.RotatethisModelCheckBox = uicheckbox(app.RotationGridPanel);
            app.RotatethisModelCheckBox.ValueChangedFcn = createCallbackFcn(app, @RotatethisModelCheckBoxValueChanged, true);
            app.RotatethisModelCheckBox.Text = 'Rotate this Model';
            app.RotationSpinnerPanel = uipanel(app.RotationGridPanel);
            app.RotatexdegreesSpinnerLabel = uilabel(app.RotationSpinnerPanel);
            app.RotatexdegreesSpinnerLabel.Text = 'Rotate x (degrees)';
            app.RotatexdegreesSpinner = uispinner(app.RotationSpinnerPanel);
            app.RotatexdegreesSpinner.Step = 0.1;
            app.RotatexdegreesSpinner.Limits = [-360 360];
            app.RotatexdegreesSpinner.Enable = 'off';
            app.RotateydegreesSpinnerLabel = uilabel(app.RotationSpinnerPanel);
            app.RotateydegreesSpinnerLabel.Text = 'Rotate y (degrees)';
            app.RotateydegreesSpinner = uispinner(app.RotationSpinnerPanel);
            app.RotateydegreesSpinner.Step = 0.1;
            app.RotateydegreesSpinner.Limits = [-360 360];
            app.RotateydegreesSpinner.Enable = 'off';
            app.RotatezdegreesSpinnerLabel = uilabel(app.RotationSpinnerPanel);
            app.RotatezdegreesSpinnerLabel.Text = 'Rotate z (degrees)';
            app.RotatezdegreesSpinner = uispinner(app.RotationSpinnerPanel);
            app.RotatezdegreesSpinner.Step = 0.1;
            app.RotatezdegreesSpinner.Limits = [-360 360];
            app.RotatezdegreesSpinner.Enable = 'off';
            app.ClearTransformButton = uibutton(app.GridLayout5_2, 'push');
            app.ClearTransformButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTransformButtonPushed, true);
            app.ClearTransformButton.Icon = 'img_erase_22px.png';
            app.ClearTransformButton.Enable = 'off';
            app.ClearTransformButton.Text = 'Clear Transform';
            app.ApplyTransformtoModelButton = uibutton(app.GridLayout5_2, 'push');
            app.ApplyTransformtoModelButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyTransformtoModelButtonPushed, true);
            app.ApplyTransformtoModelButton.Icon = 'img_transform_22px.png';
            app.ApplyTransformtoModelButton.Enable = 'off';
            app.ApplyTransformtoModelButton.Text = 'Apply Transform to Model';
            app.ConfigurePanel = uipanel(app.Module2AdaptiveFlatLayerSlicerUIFigure);
            app.ConfigurePanel.Title = 'Configure';
            app.GridLayout5_3 = uigridlayout(app.ConfigurePanel);
            app.DetailsLabel_3 = uilabel(app.GridLayout5_3);
            app.DetailsLabel_3.Text = ' ';
            app.GotoStep4SliceButton = uibutton(app.GridLayout5_3, 'push');
            app.GotoStep4SliceButton.ButtonPushedFcn = createCallbackFcn(app, @GotoStep4SliceButtonPushed, true);
            app.GotoStep4SliceButton.Icon = 'img_arrowRight_16px.png';
            app.GotoStep4SliceButton.Text = 'Go to Step 4: Slice';
            app.GridLayout7 = uigridlayout(app.GridLayout5_3);
            app.Label = uilabel(app.GridLayout7);
            app.Label.Text = 'Adaptive Slicing automatically adjusts the layer thickness throughout the print to better reproduce fine details in a model.';
            app.useAdaptiveSlicingCheckBox = uicheckbox(app.GridLayout7);
            app.useAdaptiveSlicingCheckBox.Enable = 'off';
            app.useAdaptiveSlicingCheckBox.Text = 'Use Adaptive Slicing';
            app.Panel = uipanel(app.GridLayout5_3);
            app.LayerThicknessmmSpinnerLabel = uilabel(app.Panel);
            app.LayerThicknessmmSpinnerLabel.Text = 'Layer Thickness (mm)';
            app.LayerThicknessmmSpinner = uispinner(app.Panel);
            app.LayerThicknessmmSpinner.Step = 0.01;
            app.LayerThicknessmmSpinner.Limits = [0 Inf];
            app.LayerThicknessmmSpinner.ValueChangedFcn = createCallbackFcn(app, @LayerThicknessmmSpinnerValueChanged, true);
            app.LayerThicknessmmSpinner.Value = 1;
            app.SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel_5 = uilabel(app.GridLayout5_3);
            app.SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel_5.Text = 'Configure the parameters for slicing if necessary:';
            app.SlicePanel = uipanel(app.Module2AdaptiveFlatLayerSlicerUIFigure);
            app.SlicePanel.Title = 'Slice';
            app.GridLayout5_4 = uigridlayout(app.SlicePanel);
            app.GridLayout6_4 = uigridlayout(app.GridLayout5_4);
            app.SliceButton = uibutton(app.GridLayout6_4, 'push');
            app.SliceButton.ButtonPushedFcn = createCallbackFcn(app, @SliceButtonPushed, true);
            app.SliceButton.Icon = 'img_run_22px.png';
            app.SliceButton.Text = 'Slice';
            app.ClearSTLButton_4 = uibutton(app.GridLayout6_4, 'push');
            app.ClearSTLButton_4.Icon = 'img_erase_22px.png';
            app.ClearSTLButton_4.Enable = 'off';
            app.ClearSTLButton_4.Text = 'Clear STL...';
            app.SlicerLabel = uilabel(app.GridLayout5_4);
            app.SlicerLabel.Text = 'Slicer';
            app.SelectedFileNameLabel_4 = uilabel(app.GridLayout5_4);
            app.SelectedFileNameLabel_4.Text = 'Slicer is Ready';
            % Show the figure after all components are created
            app.Module2AdaptiveFlatLayerSlicerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module2GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Module2AdaptiveFlatLayerSlicerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Module2AdaptiveFlatLayerSlicerUIFigure)
        end
    end
end