classdef Module3GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Module3TriangulatedFreeformSurfacesUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        StepButtonGrid                  matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        Step1Button                     matlab.ui.control.Button
        Step2Button                     matlab.ui.control.Button
        MainViewsGrid                   matlab.ui.container.GridLayout
        taskPanel                       matlab.ui.container.GridLayout
        NoModelLabel                    matlab.ui.control.Label
        previewAxis                     matlab.ui.control.UIAxes
        DefineBxByPanel                 matlab.ui.container.Panel
        TransformPanelGridLayout        matlab.ui.container.GridLayout
        GridLayout22                    matlab.ui.container.GridLayout
        GridLayout21_2                  matlab.ui.container.GridLayout
        Image12_2                       matlab.ui.control.Image
        DefineBxLabel                   matlab.ui.control.Label
        bXTable                         matlab.ui.control.Table
        GridLayout23                    matlab.ui.container.GridLayout
        GridLayout22_2                  matlab.ui.container.GridLayout
        GridLayout21_3                  matlab.ui.container.GridLayout
        Image12_3                       matlab.ui.control.Image
        DefineByLabel                   matlab.ui.control.Label
        bYTable                         matlab.ui.control.Table
        AutoGenXYRadios                 matlab.ui.container.GridLayout
        GridLayout20                    matlab.ui.container.GridLayout
        Image8                          matlab.ui.control.Image
        BxByGenerationLabel             matlab.ui.control.Label
        AutoGenXYRadioGroup             matlab.ui.container.ButtonGroup
        ManuallyGenerateBxByButton      matlab.ui.control.RadioButton
        AutoGenerateUniformBxByPointsButton  matlab.ui.control.RadioButton
        Label_3                         matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        MaximumYEditFieldLabel          matlab.ui.control.Label
        MaximumYEditField               matlab.ui.control.NumericEditField
        MaximumXEditFieldLabel          matlab.ui.control.Label
        MaximumXEditField               matlab.ui.control.NumericEditField
        GotoStep2TriangulateButton      matlab.ui.control.Button
        SwitchToBezierDefinePanelButton  matlab.ui.control.Button
        TriangulatePanel                matlab.ui.container.Panel
        GridLayout5_3                   matlab.ui.container.GridLayout
        SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel_5  matlab.ui.control.Label
        TriangulationResolutionLayout   matlab.ui.container.GridLayout
        TriangulationHero               matlab.ui.container.GridLayout
        Image13                         matlab.ui.control.Image
        TriangulationResolutionLabel    matlab.ui.control.Label
        ResolutionDescriptionLabel      matlab.ui.control.Label
        ResolutionSpinnerPanel          matlab.ui.container.Panel
        ResolutionSpinnerLabel          matlab.ui.control.Label
        ResolutionSpinner               matlab.ui.control.Spinner
        GenerateTriangulationButton     matlab.ui.control.Button
        GridLayout24                    matlab.ui.container.GridLayout
        ExportTriangulationToXlsxButton  matlab.ui.control.Button
        ExportTriangulationToSTLButton  matlab.ui.control.Button
        DefineBezierCurvePanel          matlab.ui.container.Panel
        DefinePanelLayout               matlab.ui.container.GridLayout
        DefinethepropertiesoftheBezierCurvetogenerateLabel  matlab.ui.control.Label
        bZTableLayout                   matlab.ui.container.GridLayout
        GridLayout21                    matlab.ui.container.GridLayout
        Image12                         matlab.ui.control.Image
        DefineBzLabel                   matlab.ui.control.Label
        bZTable                         matlab.ui.control.Table
        GoToStep2Button                 matlab.ui.control.Button
        SquareMatrixSizeSpinnerPanel    matlab.ui.container.Panel
        SquareMatrixSizeSpinnerLabel    matlab.ui.control.Label
        SquareMatrixSizeSpinner         matlab.ui.control.Spinner
        SwitchToBxByPanelButton         matlab.ui.control.Button
        BxyWarningLabel                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        plotLimits;                                 % The limits for the plot
        
        
        primaryAccent = [0.02, 0.62, 0.87];         % GUI Properties
        activeStepButtonColour = [0.99, 0.99, 0.99];
        inactiveStepButtonColour = [0.85, 0.85, 0.85];
        
        bDefaultColumnWidth = 36;
        
        bX = zeros(5);
        bY = zeros(5);
        bZ = zeros(5);
        
        pZ = [1 2 3 2 1;
                2 3 4 3 2;
                3 4 5 4 3;
                2 3 4 3 2;
                1 2 3 2 1];
        pY = [];
        pX = [];
        
        triangRes = 20;
        
        bezierTriangulation = [];

    end
    
    methods (Access = private)
        
        % Clear Preview
        function clearPreview(app)
            
            % Hide the plot and show the No Curve Generated label
            app.previewAxis.Visible = 0;
            cla(app.previewAxis);
            app.NoModelLabel.Visible = 1;
        
        end

        function updateStepControlColour(app)
            
            app.Step1Button.BackgroundColor = app.inactiveStepButtonColour;
            app.Step2Button.BackgroundColor = app.inactiveStepButtonColour;
            
        end
        
        % Update the window with a view of the triangulated bezier curve
        function updateBezierTriangulationPreview(app)
            
            % Clear plot, then draw the curve
            app.NoModelLabel.Visible = 0;
            cla(app.previewAxis);
            view(app.previewAxis,3);
            
            % Plot the Triangulation
            app.previewAxis.Visible = 1;
            patch(app.previewAxis, 'Faces', app.bezierTriangulation.ConnectivityList, 'Vertices', app.bezierTriangulation.Points, 'EdgeColor',[0 0.5 0.5],'FaceColor','none')
            
        end

        % Updates the Side Panel to remove the children when transitioning
        % to another panel
        function removeChildPanel(app)
            app.DefineBezierCurvePanel.Parent = [];
            app.DefineBxByPanel.Parent = [];
            app.TriangulatePanel.Parent = [];
        end

        % Updates the plot limits which bound the default viewing region of
        % the 3D Object. Enforces a 1:1:1 scaling across axis'.
        function updatePlotLimits(app)
            
            % Find the size of each plane of the model
            xSize = max(app.stlModel.Points(:,1)) - min(app.stlModel.Points(:,1));
            ySize = max(app.stlModel.Points(:,2)) - min(app.stlModel.Points(:,2));
            zSize = max(app.stlModel.Points(:,3)) - min(app.stlModel.Points(:,3));
            
            % The largest size will determine the size of each plane
            bufferZone = 5;
            planeSize = max(max(xSize, ySize), zSize) + 2 * bufferZone;
            planeBuff = planeSize / 2;
            
            % Find the center of the model
            xCenter = min(app.stlModel.Points(:,1)) + (xSize / 2);
            yCenter = min(app.stlModel.Points(:,2)) + (ySize / 2);
            
            % Update the Plot Limits
            % Note that for the model to sit on the Z axis, the Z should
            % start at 0 and end at planeSize
            app.plotLimits = [xCenter - planeBuff, xCenter + planeBuff;
                                yCenter - planeBuff, yCenter + planeBuff;
                                0, planeSize];

        end
        
        % Update the dimensions of the Bz Table depending on the Spinner
        % Value
        function updateBTableDimensions(app)
            
            dimensions = app.SquareMatrixSizeSpinner.Value;
            app.updateTable(app.bXTable, app.bX, dimensions);
            app.updateTable(app.bYTable, app.bY, dimensions);
            app.updateTable(app.bZTable, app.bZ, dimensions);
            
        end
        
        function updateTable(app, uiTable, table, dimensions)
            
            % If existing table size !=, then change to zeros
            if height(table) ~= dimensions
                table = zeros(dimensions);
                
            end
            
            columnWidths = ones(1, dimensions) * app.bDefaultColumnWidth;
            uiTable.Data = table;
            uiTable.RowName = 'numbered';
            uiTable.ColumnName = 'numbered';
            uiTable.ColumnWidth = num2cell(columnWidths);
            uiTable.ColumnEditable = true;
            
        end
        
        function updateAutoGenerateBxy(app)
            
            % Get the selection of the Bx, By Generation
            isAuto = app.AutoGenerateUniformBxByPointsButton.Value;
            
            % Enable/Disable the Spinners
            app.MaximumXEditField.Enable = isAuto;
            app.MaximumYEditField.Enable = isAuto;
            
            % Enable/Disable the Bx & By Tables
            if isAuto
                app.bXTable.Enable = 'off';
                app.bYTable.Enable = 'off';
            else
                app.bXTable.Enable = 'on';
                app.bYTable.Enable = 'on';
            end
            
        end
        
        function autoGenBxy(app)
            
            dim = app.SquareMatrixSizeSpinner.Value;
            
            % Obtain the desired maximum values
            maxX = app.MaximumXEditField.Value;
            maxY = app.MaximumYEditField.Value;
            
            % Determine divisors
            divX = maxX / (dim - 1);
            divY = maxY / (dim - 1);
            
            % Generate the matrices
            %app.bX = zeros(dim);
            xVals = 0:divX:maxX;
            %app.bX(1:end, :) = xVals;
            app.bX = repmat(xVals,dim,1);
            
            %app.bY = zeros(dim);
            yVals = (0:divY:maxY)';
            %app.bY(:, 1:end) = ;
            app.bY = repmat(yVals,1,dim);
            
            % Update Tables
            updateBTableDimensions(app);
            
        end
          
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dummy)
            app.DefineBezierCurvePanel.Parent = app.taskPanel;
            autoGenBxy(app);
            app.bZTable.Data = app.bZ;
        end

        % Button pushed function: Step1Button
        function Step1ButtonPushed(app, event)
            
            % Set the Active Button Colour
            app.updateStepControlColour();
            app.Step1Button.BackgroundColor = app.activeStepButtonColour;
            
            % Update the Side Panel
            app.removeChildPanel();
            app.DefineBezierCurvePanel.Parent = app.taskPanel;
            
        end

        % Callback function
        function Step2ButtonPushed(app, event)
            
            % Set the Active Button Colour
            app.updateStepControlColour();
            app.Step2Button.BackgroundColor = app.activeStepButtonColour;
            
            % Update the Side Panel
            app.removeChildPanel();
            app.TriangulatePanel.Parent = app.taskPanel;
            
        end

        % Button pushed function: GoToStep2Button
        function GoToStep2ButtonPushed(app, event)
            Step2ButtonPushed(app,event);
        end

        % Button pushed function: GotoStep2TriangulateButton
        function GotoStep2TriangulateButtonPushed(app, event)
            Step2ButtonPushed(app,event);
        end

        % Value changed function: SquareMatrixSizeSpinner
        function SquareMatrixSizeSpinnerValueChanged(app, event)
            clearPreview(app)
            app.updateBTableDimensions();
        end

        % Cell edit callback: bZTable
        function bZTableCellEdit(app, event)
            
            clearPreview(app);
            
            indices = event.Indices;
            newData = event.NewData;
            i = indices(:,1);
            j = indices(:,2);
            
            if ~isnan(newData) && isnumeric(newData)
                app.bZ(i,j) = newData;
            else
                app.bZTable.Data = app.bZ;
            end
            
        end

        % Selection changed function: AutoGenXYRadioGroup
        function AutoGenXYRadioGroupSelectionChanged(app, event)
            
            % Update the Panel Options & Step Buttons based on the Radio Selection
            clearPreview(app)
            app.updateAutoGenerateBxy();
            
        end

        % Value changed function: MaximumYEditField
        function MaximumYEditFieldValueChanged(app, event)
            
            % Generate the Bx and By matrices automatically
            clearPreview(app)
            autoGenBxy(app);
            
        end

        % Value changed function: MaximumXEditField
        function MaximumXEditFieldValueChanged(app, event)

            % Generate the Bx and By matrices automatically
            clearPreview(app)
            autoGenBxy(app);
            
        end

        % Button pushed function: GenerateTriangulationButton
        function GenerateTriangulationButtonPushed(app, event)
            
            % Triangulate
            [app.pX, app.pY, app.pZ] = GenerateBezierCurve(app.bX,app.bY,app.bZ,app.triangRes);
            app.bezierTriangulation = tri(app.pX, app.pY, app.pZ);
            
            % Plot
            updateBezierTriangulationPreview(app);
            
            % Enable Export Button and Step 3
            app.ExportTriangulationToXlsxButton.Enable = 1;
            app.ExportTriangulationToSTLButton.Enable = 1;
            
        end

        % Value changed function: ResolutionSpinner
        function ResolutionSpinnerValueChanged(app, event)
            app.triangRes = app.ResolutionSpinner.Value;
        end

        % Button pushed function: SwitchToBxByPanelButton
        function SwitchToBxByPanelButtonPushed(app, event)
            
            % Hide the Bx By Warning Label
            app.BxyWarningLabel.Visible = false;
            
            % Enable the Step 2 Button
            app.GoToStep2Button.Enable = 1;
            
            % Switch to the Bx By Panel
            app.removeChildPanel();
            app.DefineBxByPanel.Parent = app.taskPanel;
            
        end

        % Button pushed function: SwitchToBezierDefinePanelButton
        function SwitchToBezierDefinePanelButtonPushed(app, event)
            app.removeChildPanel();
            app.DefineBezierCurvePanel.Parent = app.taskPanel;
        end

        % Button pushed function: ExportTriangulationToXlsxButton
        function ExportTriangulationToXlsxButtonPushed(app, event)
            
            % Show progress bar
            progressBar = uiprogressdlg(app.Module3TriangulatedFreeformSurfacesUIFigure, 'Title','Please Wait','Message','Waiting for user to select file location...','Indeterminate','on');
            
            try
                fileTypes = {'*.xlsx', 'Excel Spreadsheet'};
                [fileName, filePath] = uiputfile(fileTypes, 'Export to Excel Spreadsheet...');
                addpath(filePath);
                progressBar.Message = "Exporting data";
                writematrix(app.bezierTriangulation.ConnectivityList, filePath + "\" + fileName, 'Sheet',1);
                writematrix(app.bezierTriangulation.Points, filePath + "\" + fileName, 'Sheet',1);
                writematrix(app.slicerPath, filePath + "\" + fileName, 'Sheet',1);
                helpdlg("The file has been successfully exported.");
                close(progressBar);
            catch exception
                errordlg("There was an issue exporting a Excel Spreadsheet: " + exception.message + ". This error might occur if you cancel the save prompt, or if you select a file location that isn't accessible on your computer.");
                close(progressBar);
                return;
            end
            
        end

        % Button pushed function: ExportTriangulationToSTLButton
        function ExportTriangulationToSTLButtonPushed(app, event)
            
            % Show progress bar
            progressBar = uiprogressdlg(app.Module3TriangulatedFreeformSurfacesUIFigure, 'Title','Please Wait','Message','Waiting for user to select file location...','Indeterminate','on');
            
            try
                fileTypes = {'*.stl', 'STL File'};
                [fileName, filePath] = uiputfile(fileTypes, 'Export to STL...');
                addpath(filePath);
                progressBar.Message = "Exporting data";
                stlwrite(app.bezierTriangulation, filePath + "\" + fileName);
                helpdlg("The file has been successfully exported.");
                close(progressBar);
            catch exception
                errordlg("There was an issue exporting a .stl file: " + exception.message + ". This error might occur if you cancel the save prompt, or if you select a file location that isn't accessible on your computer.");
                close(progressBar);
                return;
            end
            
        end

        % Cell edit callback: bXTable
        function bXTableCellEdit(app, event)

            clearPreview(app);
            
            indices = event.Indices;
            newData = event.NewData;
            i = indices(:,1);
            j = indices(:,2);
            
            if ~isnan(newData) && isnumeric(newData)
                app.bX(i,j) = newData;
            else
                app.bXTable.Data = app.bX;
            end
            
        end

        % Cell edit callback: bYTable
        function bYTableCellEdit(app, event)
            
            clearPreview(app);
            
            indices = event.Indices;
            newData = event.NewData;
            i = indices(:,1);
            j = indices(:,2);
            
            if ~isnan(newData) && isnumeric(newData)
                app.bY(i,j) = newData;
            else
                app.bYTable.Data = app.bY;
            end
            
        end

        % Button pushed function: Step2Button
        function Step2ButtonPushed2(app, event)
            Step2ButtonPushed(app,event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Module3TriangulatedFreeformSurfacesUIFigure = uifigure('Visible', 'off');
            app.Module3TriangulatedFreeformSurfacesUIFigure.Name = 'Module 3: Triangulated Freeform Surfaces';
            app.Module3TriangulatedFreeformSurfacesUIFigure.Icon = 'img_stlRes_22px.png';
            app.GridLayout = uigridlayout(app.Module3TriangulatedFreeformSurfacesUIFigure);
            app.StepButtonGrid = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.StepButtonGrid);
            app.Step1Button = uibutton(app.GridLayout4, 'push');
            app.Step1Button.ButtonPushedFcn = createCallbackFcn(app, @Step1ButtonPushed, true);
            app.Step1Button.Icon = 'img_bezier_32px.png';
            app.Step1Button.Text = '1. Define Bezier';
            app.Step2Button = uibutton(app.GridLayout4, 'push');
            app.Step2Button.ButtonPushedFcn = createCallbackFcn(app, @Step2ButtonPushed2, true);
            app.Step2Button.Icon = 'img_triangulate_32px.png';
            app.Step2Button.Text = '2. Triangulate';
            app.MainViewsGrid = uigridlayout(app.GridLayout);
            app.taskPanel = uigridlayout(app.MainViewsGrid);
            app.NoModelLabel = uilabel(app.MainViewsGrid);
            app.NoModelLabel.Text = {'Curve Not Generated. '; 'Complete Steps 1 & 2, and select "Generate Curve" to see a preview here.'};
            app.previewAxis = uiaxes(app.MainViewsGrid);
            xlabel(app.previewAxis, 'X')
            ylabel(app.previewAxis, 'Y')
            zlabel(app.previewAxis, 'Z')
            app.previewAxis.Visible = 'off';
            app.DefineBxByPanel = uipanel(app.Module3TriangulatedFreeformSurfacesUIFigure);
            app.DefineBxByPanel.Title = 'Define Bx By';
            app.TransformPanelGridLayout = uigridlayout(app.DefineBxByPanel);
            app.GridLayout22 = uigridlayout(app.TransformPanelGridLayout);
            app.GridLayout21_2 = uigridlayout(app.GridLayout22);
            app.Image12_2 = uiimage(app.GridLayout21_2);
            app.Image12_2.ImageSource = 'img_table_22px.png';
            app.DefineBxLabel = uilabel(app.GridLayout21_2);
            app.DefineBxLabel.Text = 'Define Bx';
            app.bXTable = uitable(app.GridLayout22);
            app.bXTable.ColumnName = '';
            app.bXTable.RowName = {};
            app.bXTable.CellEditCallback = createCallbackFcn(app, @bXTableCellEdit, true);
            app.bXTable.Enable = 'off';
            app.GridLayout23 = uigridlayout(app.TransformPanelGridLayout);
            app.GridLayout22_2 = uigridlayout(app.GridLayout23);
            app.GridLayout21_3 = uigridlayout(app.GridLayout22_2);
            app.Image12_3 = uiimage(app.GridLayout21_3);
            app.Image12_3.ImageSource = 'img_table_22px.png';
            app.DefineByLabel = uilabel(app.GridLayout21_3);
            app.DefineByLabel.Text = 'Define By';
            app.bYTable = uitable(app.GridLayout23);
            app.bYTable.ColumnName = '';
            app.bYTable.RowName = {};
            app.bYTable.CellEditCallback = createCallbackFcn(app, @bYTableCellEdit, true);
            app.bYTable.Enable = 'off';
            app.AutoGenXYRadios = uigridlayout(app.TransformPanelGridLayout);
            app.GridLayout20 = uigridlayout(app.AutoGenXYRadios);
            app.Image8 = uiimage(app.GridLayout20);
            app.Image8.ImageSource = 'img_greenWand_22px.png';
            app.BxByGenerationLabel = uilabel(app.GridLayout20);
            app.BxByGenerationLabel.Text = 'Bx & By Generation';
            app.AutoGenXYRadioGroup = uibuttongroup(app.AutoGenXYRadios);
            app.AutoGenXYRadioGroup.SelectionChangedFcn = createCallbackFcn(app, @AutoGenXYRadioGroupSelectionChanged, true);
            app.ManuallyGenerateBxByButton = uiradiobutton(app.AutoGenXYRadioGroup);
            app.ManuallyGenerateBxByButton.Text = 'Manually Generate Bx & By';
            app.AutoGenerateUniformBxByPointsButton = uiradiobutton(app.AutoGenXYRadioGroup);
            app.AutoGenerateUniformBxByPointsButton.Text = 'Auto-Generate Uniform Bx & By Points';
            app.AutoGenerateUniformBxByPointsButton.Value = true;
            app.Label_3 = uilabel(app.AutoGenXYRadios);
            app.Label_3.Text = 'Auto-Generate generates uniform Bx, By points between 0 and the maximum values.';
            app.Panel_3 = uipanel(app.AutoGenXYRadios);
            app.MaximumYEditFieldLabel = uilabel(app.Panel_3);
            app.MaximumYEditFieldLabel.Text = 'Maximum Y';
            app.MaximumYEditField = uieditfield(app.Panel_3, 'numeric');
            app.MaximumYEditField.Limits = [0.01 Inf];
            app.MaximumYEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumYEditFieldValueChanged, true);
            app.MaximumYEditField.Value = 10;
            app.MaximumXEditFieldLabel = uilabel(app.Panel_3);
            app.MaximumXEditFieldLabel.Text = 'Maximum X';
            app.MaximumXEditField = uieditfield(app.Panel_3, 'numeric');
            app.MaximumXEditField.Limits = [0.01 Inf];
            app.MaximumXEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumXEditFieldValueChanged, true);
            app.MaximumXEditField.Value = 10;
            app.GotoStep2TriangulateButton = uibutton(app.TransformPanelGridLayout, 'push');
            app.GotoStep2TriangulateButton.ButtonPushedFcn = createCallbackFcn(app, @GotoStep2TriangulateButtonPushed, true);
            app.GotoStep2TriangulateButton.Icon = 'img_arrowRight_16px.png';
            app.GotoStep2TriangulateButton.Text = 'Go to Step 2: Triangulate';
            app.SwitchToBezierDefinePanelButton = uibutton(app.TransformPanelGridLayout, 'push');
            app.SwitchToBezierDefinePanelButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchToBezierDefinePanelButtonPushed, true);
            app.SwitchToBezierDefinePanelButton.Icon = 'img_bezier_32px.png';
            app.SwitchToBezierDefinePanelButton.Text = 'Define Matrix Size & Bz';
            app.TriangulatePanel = uipanel(app.Module3TriangulatedFreeformSurfacesUIFigure);
            app.TriangulatePanel.Title = 'Triangulate';
            app.GridLayout5_3 = uigridlayout(app.TriangulatePanel);
            app.SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel_5 = uilabel(app.GridLayout5_3);
            app.SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel_5.Text = 'Configure the resolution for triangulation and select "Generate Triangulation".';
            app.TriangulationResolutionLayout = uigridlayout(app.GridLayout5_3);
            app.TriangulationHero = uigridlayout(app.TriangulationResolutionLayout);
            app.Image13 = uiimage(app.TriangulationHero);
            app.Image13.ImageSource = 'img_stlRes_32px.png';
            app.TriangulationResolutionLabel = uilabel(app.TriangulationHero);
            app.TriangulationResolutionLabel.Text = 'Triangulation Resolution';
            app.ResolutionDescriptionLabel = uilabel(app.TriangulationResolutionLayout);
            app.ResolutionDescriptionLabel.Text = 'A higher triangulation resolution will generate a smoother curve, but at the expense of storage and compute time.';
            app.ResolutionSpinnerPanel = uipanel(app.TriangulationResolutionLayout);
            app.ResolutionSpinnerLabel = uilabel(app.ResolutionSpinnerPanel);
            app.ResolutionSpinnerLabel.Text = 'Resolution';
            app.ResolutionSpinner = uispinner(app.ResolutionSpinnerPanel);
            app.ResolutionSpinner.Limits = [2 Inf];
            app.ResolutionSpinner.ValueChangedFcn = createCallbackFcn(app, @ResolutionSpinnerValueChanged, true);
            app.ResolutionSpinner.Value = 20;
            app.GenerateTriangulationButton = uibutton(app.GridLayout5_3, 'push');
            app.GenerateTriangulationButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTriangulationButtonPushed, true);
            app.GenerateTriangulationButton.Icon = 'img_run_22px.png';
            app.GenerateTriangulationButton.Text = 'Generate Triangulation';
            app.GridLayout24 = uigridlayout(app.GridLayout5_3);
            app.ExportTriangulationToXlsxButton = uibutton(app.GridLayout24, 'push');
            app.ExportTriangulationToXlsxButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTriangulationToXlsxButtonPushed, true);
            app.ExportTriangulationToXlsxButton.Icon = 'img_save_22px.png';
            app.ExportTriangulationToXlsxButton.Enable = 'off';
            app.ExportTriangulationToXlsxButton.Tooltip = {'Export the last Generated Triangulation as Excel Spreadsheet, containinig two sheets - a Connectivity List, and a set of Points (Vertices).'};
            app.ExportTriangulationToXlsxButton.Text = {'Save Excel'; ''; ''};
            app.ExportTriangulationToSTLButton = uibutton(app.GridLayout24, 'push');
            app.ExportTriangulationToSTLButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTriangulationToSTLButtonPushed, true);
            app.ExportTriangulationToSTLButton.Icon = 'img_save_22px.png';
            app.ExportTriangulationToSTLButton.Enable = 'off';
            app.ExportTriangulationToSTLButton.Tooltip = {'Export the last Generated Triangulation as an STL file.'};
            app.ExportTriangulationToSTLButton.Text = {'Save STL'; ''; ''};
            app.DefineBezierCurvePanel = uipanel(app.Module3TriangulatedFreeformSurfacesUIFigure);
            app.DefineBezierCurvePanel.Title = 'Define Bezier Curve';
            app.DefinePanelLayout = uigridlayout(app.DefineBezierCurvePanel);
            app.DefinethepropertiesoftheBezierCurvetogenerateLabel = uilabel(app.DefinePanelLayout);
            app.DefinethepropertiesoftheBezierCurvetogenerateLabel.Text = 'Define the properties of the Bezier Curve to generate.';
            app.bZTableLayout = uigridlayout(app.DefinePanelLayout);
            app.GridLayout21 = uigridlayout(app.bZTableLayout);
            app.Image12 = uiimage(app.GridLayout21);
            app.Image12.ImageSource = 'img_table_22px.png';
            app.DefineBzLabel = uilabel(app.GridLayout21);
            app.DefineBzLabel.Text = 'Define Bz';
            app.bZTable = uitable(app.bZTableLayout);
            app.bZTable.ColumnName = '';
            app.bZTable.RowName = {};
            app.bZTable.CellEditCallback = createCallbackFcn(app, @bZTableCellEdit, true);
            app.GoToStep2Button = uibutton(app.DefinePanelLayout, 'push');
            app.GoToStep2Button.ButtonPushedFcn = createCallbackFcn(app, @GoToStep2ButtonPushed, true);
            app.GoToStep2Button.Icon = 'img_arrowRight_16px.png';
            app.GoToStep2Button.Enable = 'off';
            app.GoToStep2Button.Text = 'Go to Step 2: Triangulate';
            app.SquareMatrixSizeSpinnerPanel = uipanel(app.DefinePanelLayout);
            app.SquareMatrixSizeSpinnerLabel = uilabel(app.SquareMatrixSizeSpinnerPanel);
            app.SquareMatrixSizeSpinnerLabel.Text = 'Square Matrix Size';
            app.SquareMatrixSizeSpinner = uispinner(app.SquareMatrixSizeSpinnerPanel);
            app.SquareMatrixSizeSpinner.Limits = [2 Inf];
            app.SquareMatrixSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @SquareMatrixSizeSpinnerValueChanged, true);
            app.SquareMatrixSizeSpinner.Value = 5;
            app.SwitchToBxByPanelButton = uibutton(app.DefinePanelLayout, 'push');
            app.SwitchToBxByPanelButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchToBxByPanelButtonPushed, true);
            app.SwitchToBxByPanelButton.Icon = 'img_pathSelection_22px.png';
            app.SwitchToBxByPanelButton.Text = 'Define Bx & By';
            app.BxyWarningLabel = uilabel(app.DefinePanelLayout);
            app.BxyWarningLabel.Text = 'Bx & By not defined!';
            % Show the figure after all components are created
            app.Module3TriangulatedFreeformSurfacesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module3GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Module3TriangulatedFreeformSurfacesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Module3TriangulatedFreeformSurfacesUIFigure)
        end
    end
end