classdef Module4GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Module4CurvedLayerSlicingUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        StepButtonGrid                  matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        Step1Button                     matlab.ui.control.Button
        Step2Button                     matlab.ui.control.Button
        Step4Button                     matlab.ui.control.Button
        Step3Button                     matlab.ui.control.Button
        MainViewsGrid                   matlab.ui.container.GridLayout
        taskPanel                       matlab.ui.container.GridLayout
        NoModelLabel                    matlab.ui.control.Label
        previewAxis                     matlab.ui.control.UIAxes
        DefineBxByPanel                 matlab.ui.container.Panel
        TransformPanelGridLayout        matlab.ui.container.GridLayout
        GridLayout22                    matlab.ui.container.GridLayout
        GridLayout21_2                  matlab.ui.container.GridLayout
        Image12_2                       matlab.ui.control.Image
        DefineBxLabel                   matlab.ui.control.Label
        bXTable                         matlab.ui.control.Table
        GridLayout23                    matlab.ui.container.GridLayout
        GridLayout22_2                  matlab.ui.container.GridLayout
        GridLayout21_3                  matlab.ui.container.GridLayout
        Image12_3                       matlab.ui.control.Image
        DefineByLabel                   matlab.ui.control.Label
        bYTable                         matlab.ui.control.Table
        AutoGenXYRadios                 matlab.ui.container.GridLayout
        GridLayout20                    matlab.ui.container.GridLayout
        Image8                          matlab.ui.control.Image
        BxByGenerationLabel             matlab.ui.control.Label
        AutoGenXYRadioGroup             matlab.ui.container.ButtonGroup
        ManuallyGenerateBxByButton      matlab.ui.control.RadioButton
        AutoGenerateUniformBxByPointsButton  matlab.ui.control.RadioButton
        Label_3                         matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        MaximumYEditFieldLabel          matlab.ui.control.Label
        MaximumYEditField               matlab.ui.control.NumericEditField
        MaximumXEditFieldLabel          matlab.ui.control.Label
        MaximumXEditField               matlab.ui.control.NumericEditField
        GotoStep2TriangulateButton      matlab.ui.control.Button
        SwitchToBezierDefinePanelButton  matlab.ui.control.Button
        TriangulatePanel                matlab.ui.container.Panel
        GridLayout5_3                   matlab.ui.container.GridLayout
        SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel_5  matlab.ui.control.Label
        GotoStep3Button                 matlab.ui.control.Button
        TriangulationResolutionLayout   matlab.ui.container.GridLayout
        TriangulationHero               matlab.ui.container.GridLayout
        Image13                         matlab.ui.control.Image
        TriangulationResolutionLabel    matlab.ui.control.Label
        ResolutionDescriptionLabel      matlab.ui.control.Label
        ResolutionSpinnerPanel          matlab.ui.container.Panel
        ResolutionSpinnerLabel          matlab.ui.control.Label
        ResolutionSpinner               matlab.ui.control.Spinner
        GenerateTriangulationButton     matlab.ui.control.Button
        GridLayout24                    matlab.ui.container.GridLayout
        ExportTriangulationToXlsxButton  matlab.ui.control.Button
        ExportTriangulationToSTLButton  matlab.ui.control.Button
        SlicePanel                      matlab.ui.container.Panel
        GridLayout5_4                   matlab.ui.container.GridLayout
        GridLayout6_4                   matlab.ui.container.GridLayout
        ResetViewButton                 matlab.ui.control.Button
        ExportDataButton                matlab.ui.control.Button
        SelectedFileNameLabel_4         matlab.ui.control.Label
        SliceModelButton                matlab.ui.control.Button
        GridLayout16                    matlab.ui.container.GridLayout
        GridLayout17                    matlab.ui.container.GridLayout
        ExploreLayersLabel              matlab.ui.control.Label
        Image5                          matlab.ui.control.Image
        Panel_2                         matlab.ui.container.Panel
        LayerLabel                      matlab.ui.control.Label
        LayerViewSlider                 matlab.ui.control.Slider
        LayerViewNumberLabel            matlab.ui.control.Label
        IsolateLayerinPreviewCheckBox   matlab.ui.control.CheckBox
        SliceHeroLabel                  matlab.ui.control.Label
        AnimationGrid                   matlab.ui.container.GridLayout
        AnimationHero                   matlab.ui.container.GridLayout
        AnimationTitle                  matlab.ui.control.Label
        AnimationImg                    matlab.ui.control.Image
        AnimationSpeedPanel             matlab.ui.container.Panel
        StepSpeedsecLabel               matlab.ui.control.Label
        AnimationSpeedSpinner           matlab.ui.control.Spinner
        AnimateButton                   matlab.ui.control.Button
        AnimationOptionRadioControlGroup  matlab.ui.container.ButtonGroup
        AnimateSelectedSliceButton      matlab.ui.control.RadioButton
        AnimateuptoSelectedSliceButton  matlab.ui.control.RadioButton
        AnimateentireModelButton        matlab.ui.control.RadioButton
        RasteriserOptions               matlab.ui.container.Panel
        DefineBezierCurvePanel          matlab.ui.container.Panel
        DefinePanelLayout               matlab.ui.container.GridLayout
        DefinethepropertiesoftheBezierCurvetogenerateLabel  matlab.ui.control.Label
        bZTableLayout                   matlab.ui.container.GridLayout
        GridLayout21                    matlab.ui.container.GridLayout
        Image12                         matlab.ui.control.Image
        DefineBzLabel                   matlab.ui.control.Label
        bZTable                         matlab.ui.control.Table
        SquareMatrixSizeSpinnerPanel    matlab.ui.container.Panel
        SquareMatrixSizeSpinnerLabel    matlab.ui.control.Label
        SquareMatrixSizeSpinner         matlab.ui.control.Spinner
        SwitchToBxByPanelButton         matlab.ui.control.Button
        ImportBezierBMatrixButton       matlab.ui.control.Button
        GoToStep2Button                 matlab.ui.control.Button
        BxyWarningLabel                 matlab.ui.control.Label
        ConfigurePanel                  matlab.ui.container.Panel
        GridLayout25                    matlab.ui.container.GridLayout
        LayerThicknessPanel             matlab.ui.container.GridLayout
        LayerThicknessHero              matlab.ui.container.GridLayout
        LayerThicknessLabel             matlab.ui.control.Label
        Image14                         matlab.ui.control.Image
        Label_4                         matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        LayerThicknessLabel_2           matlab.ui.control.Label
        LayerThicknessSpinner           matlab.ui.control.Spinner
        NumberOfLayersLayout            matlab.ui.container.GridLayout
        NumberOfLayersHero              matlab.ui.container.GridLayout
        Image15                         matlab.ui.control.Image
        NumberofLayersLabel             matlab.ui.control.Label
        NumberOfLayersLabel             matlab.ui.control.Label
        NumberOfLayersPanel             matlab.ui.container.Panel
        NumberofLayersLabel_2           matlab.ui.control.Label
        NumLayerSpinner                 matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        
        bX = zeros(5);                                      % Bezier Parametric Constants
        bY = zeros(5);
        bZ = zeros(5);
        
        pZ = [];                                            % Bezier Plot Points
        pY = [];
        pX = [];
 
        bezierTriangulation = [];                           % The Triangulation Object generated by the Triangulation Algorithm


        layerThickness = 0.2;                       % Layer thickness defined by user (defaults to 0.2mm)
        
        slicerPath = [];                            % The Slicer Path generated by the generators
        numLayers = 1;
        plotLimits;                                 % The limits for the plot
        
        defaultAnimationSpeed = 0.5;                % Default animation speed
        isAnimating = 0;                            % Flag which indicates whether a layer animation is running
        
        primaryAccent = [0.02, 0.62, 0.87];         % GUI Properties
        activeStepButtonColour = [0.99, 0.99, 0.99];
        inactiveStepButtonColour = [0.85, 0.85, 0.85];
        
        bDefaultColumnWidth = 36;                           % Default Column Width for B Tables
        triangRes = 20;                                     % Resolution for Triangulation


    end
    
    methods (Access = private)
        
        function clearPreview(app)
        % Clears the preview area and shows the No Curve Generated Label
        
            app.previewAxis.Visible = 0;
            cla(app.previewAxis);
            app.NoModelLabel.Visible = 1;
        
        end
        
        function updateBezierTriangulationPreview(app)
        % Update the window with a view of the triangulated bezier curve
            
            % Clear plot, then draw the curve
            app.NoModelLabel.Visible = 0;
            cla(app.previewAxis);
            view(app.previewAxis,3);
            
            % Plot the Triangulation
            app.previewAxis.Visible = 1;
            patch(app.previewAxis, 'Faces', app.bezierTriangulation.ConnectivityList, 'Vertices', app.bezierTriangulation.Points, 'EdgeColor',[0 0.5 0.5],'FaceColor','none')
            
            % Apply Equal Scaling and Padding to Axis
            axis(app.previewAxis, 'equal', 'padded');
            
        end
                
        function updateSlicerPathPreview(app, maxLayer, isolateLayer)
        % Update the Plot with a preview of the slice paths for the print
        
            % Clear plot first
            cla(app.previewAxis);

            % Get all z-Heights from output Slicer Data
            [~, layers] = groupcounts(app.slicerPath(:,4));
            
            % Set the maxLayer if input > 0, else default to maxHeight
            if maxLayer <= 0
                maxLayer = height(layers);
            end
            
            % If isolating layer, only draw the paths associated with slice
            if isolateLayer
                
                    currLayer = layers(maxLayer);
                    layerPlotPoints = app.slicerPath(app.slicerPath(:,4) == currLayer,:);
                    
                    % Plot selected layer
                    plot3(app.previewAxis,layerPlotPoints(:,1), layerPlotPoints(:,2), layerPlotPoints(:,3),'-k','LineWidth',1);
                    hold(app.previewAxis, 'on');
                
            else
                
                % Then, for each slice draw the path(s)
                for i = 1:maxLayer
        
                    % Determine applicable z-axis slicer paths
                    currLayer = layers(i);
                    layerPlotPoints = app.slicerPath(app.slicerPath(:,4) == currLayer,:);
                    
                    % Plot selected layer
                    plot3(app.previewAxis,layerPlotPoints(:,1), layerPlotPoints(:,2), layerPlotPoints(:,3),'-k','LineWidth',1);
                    hold(app.previewAxis, 'on');
                
                end
                
            end
            
            % Apply Equal Scaling and Padding to Axis
            axis(app.previewAxis, 'equal', 'padded');
            
            % Disable the hold on the axis
            hold(app.previewAxis, 'off');
          
        end
        
        function animateSlicerPathPreview(app, layerToAnimate, delay, isolateLayer)
        % Animate the layer on the plot
        
            % Update Animating Flag to 1
            app.isAnimating = 1;
            
            % Disable Buttons
            app.updateSliceViewControls(0);
            app.AnimateButton.Enable = 1;
            app.SliceModelButton.Enable = 0;
            app.updateStepControls(0);
            
            % Match the Isolate Layer in Preview Checkbox with the desired
            % option
            app.IsolateLayerinPreviewCheckBox.Value = isolateLayer;
            
            % Clear plot first
            cla(app.previewAxis);
            
            % Change the button to show animation is in progress
            app.AnimateButton.Text = "Stop Animating";
            app.AnimateButton.Icon = 'img_stopAnimating_22px.png';
            
            % Get all z-Heights from output Slicer Data
            [~, zHeights] = groupcounts(app.slicerPath(:,3));
            numOfSlices = height(zHeights);
            
            % Determine Layers to Animation
            minLayer = 1;
            
            % If Isolating animation to a Single Layer, then set the min
            % and max layers
            if isolateLayer
                minLayer = layerToAnimate;
                maxLayer = layerToAnimate;
            elseif layerToAnimate <= 0
                maxLayer = numOfSlices;
            else
                maxLayer = layerToAnimate;
            end
            
            % Define a reference to the plot object
            plot = plot3(app.previewAxis,0,0,0);
            
            % Then, for each slice draw the path(s)
            for i = minLayer:maxLayer
    
                % Determine applicable z-axis slicer paths
                currZ = zHeights(i);
                zPlotPoints = app.slicerPath(app.slicerPath(:,3) == currZ,:);
                
                % Draw each path separately
                for j = 1:max(zPlotPoints(:,4))
                    
                    pathPlotPoints = zPlotPoints(zPlotPoints(:,4) == j,:);
                    
                     % Draw each point separately
                    for k = 1:height(pathPlotPoints) - 1
                        
                        plot.Color = 'k';
                        plot.LineWidth = 1;
                        plot = plot3(app.previewAxis,pathPlotPoints(k:k+1,1), pathPlotPoints(k:k+1,2), pathPlotPoints(k:k+1,3),'-b','LineWidth',2);
                        hold(app.previewAxis, 'on');
                        pause(delay);
                        
                        % If the Stop Animation button is selected, draw the
                        % normal preview, enable the reset button, and break
                        if app.isAnimating == 0
                            break;
                        end
                            
                    end

                    if app.isAnimating == 0
                        break;
                    end
                    
                end
                
                if app.isAnimating == 0
                    break;
                end
            
            end
            
            % Disable the hold on the axis and pause to give plot chance to
            % render.
            plot.Color = 'k';
            plot.LineWidth = 1;
            hold(app.previewAxis, 'off');
            pause(0.5);
            
            % If the animation has been stopped by the user, redraw initial
            % plot before continuing.
            if app.isAnimating == 0
                updateSlicerPathPreview(app, layerToAnimate, app.IsolateLayerinPreviewCheckBox.Value);
            end
                    
            % Re-enable the Buttons
            app.updateSliceViewControls(1);
            app.SliceModelButton.Enable = 1;
            app.updateStepControls(4);
            
            % Change the button to show animation has completed
            app.AnimateButton.Text = "Animate...";
            app.AnimateButton.Icon = 'img_animate_22px.png';
            
            % Update Animating Flag to 0
            app.isAnimating = 0;
          
        end
        
        % Update the Step Button Controls at the top of the window based on
        % the steps currently available
        function updateStepControls(app, stepNumber)
            app.Step1Button.Enable = (stepNumber >= 1);
            app.Step2Button.Enable = (stepNumber >= 2);
            app.Step3Button.Enable = (stepNumber >= 3);
            app.Step4Button.Enable = (stepNumber >= 4);
        end
        
        % Updates the Step Button Controls at the top of the window all to
        % the inactive colour
        function updateStepControlColour(app)
            app.Step1Button.BackgroundColor = app.inactiveStepButtonColour;
            app.Step2Button.BackgroundColor = app.inactiveStepButtonColour;
            app.Step3Button.BackgroundColor = app.inactiveStepButtonColour;
            app.Step4Button.BackgroundColor = app.inactiveStepButtonColour;
        end
    
        % Updates the Side Panel to remove the children when transitioning
        % to another panel
        function removeChildPanel(app)
            app.DefineBezierCurvePanel.Parent = [];
            app.DefineBxByPanel.Parent = [];
            app.TriangulatePanel.Parent = [];
            app.SlicePanel.Parent = [];
            app.ConfigurePanel.Parent = [];
        end
        
        % Update the controls on the Slice Panel
        function updateSliceViewControls(app, enable)
            
            % If enable is -1, set to a boolean
            if enable < 0
                enable = height(app.slicerPath) > 0;
            end
            
            % Enable or disable the tools based on if a Slicer Path has
            % been generated
            
            app.ExportDataButton.Enable = enable;
            app.ResetViewButton.Enable = enable;
            
            % Update the controls in the Layer View Control
            app.LayerViewSlider.Enable = enable;
            app.IsolateLayerinPreviewCheckBox.Enable = enable;
            
            % Update the controls in the Animation Panel
            app.AnimateButton.Enable = enable;
            app.AnimationSpeedSpinner.Enable = enable;
            app.AnimateentireModelButton.Enable = enable;
            app.AnimateuptoSelectedSliceButton.Enable = enable;
            app.AnimateSelectedSliceButton.Enable = enable;
            
        end
    
        % Updates the plot limits which bound the default viewing region of
        % the 3D Object. Enforces a 1:1:1 scaling across axis'.
        function updatePlotLimits(app)
            
            % Find the size of each plane of the model
            xSize = max(app.stlModel.Points(:,1)) - min(app.stlModel.Points(:,1));
            ySize = max(app.stlModel.Points(:,2)) - min(app.stlModel.Points(:,2));
            zSize = max(app.stlModel.Points(:,3)) - min(app.stlModel.Points(:,3));
            
            % The largest size will determine the size of each plane
            bufferZone = 5;
            planeSize = max(max(xSize, ySize), zSize) + 2 * bufferZone;
            planeBuff = planeSize / 2;
            
            % Find the center of the model
            xCenter = min(app.stlModel.Points(:,1)) + (xSize / 2);
            yCenter = min(app.stlModel.Points(:,2)) + (ySize / 2);
            
            % Update the Plot Limits
            % Note that for the model to sit on the Z axis, the Z should
            % start at 0 and end at planeSize
            app.plotLimits = [xCenter - planeBuff, xCenter + planeBuff;
                                yCenter - planeBuff, yCenter + planeBuff;
                                0, planeSize];

        end
        
        % Update the dimensions of the Bz Table depending on the Spinner
        % Value
        function updateBTableDimensions(app)
            
            dimensions = app.SquareMatrixSizeSpinner.Value;
            app.updateTable(app.bXTable, app.bX, dimensions);
            app.updateTable(app.bYTable, app.bY, dimensions);
            app.updateTable(app.bZTable, app.bZ, dimensions);
            
        end
        
        function updateTable(app, uiTable, table, dimensions)
            
            % If existing table size !=, then change to zeros
            if height(table) ~= dimensions
                table = zeros(dimensions);
                
            end
            
            columnWidths = ones(1, dimensions) * app.bDefaultColumnWidth;
            uiTable.Data = table;
            uiTable.RowName = 'numbered';
            uiTable.ColumnName = 'numbered';
            uiTable.ColumnWidth = num2cell(columnWidths);
            uiTable.ColumnEditable = true;
            
        end
        
        function updateAutoGenerateBxy(app)
            
            % Get the selection of the Bx, By Generation
            isAuto = app.AutoGenerateUniformBxByPointsButton.Value;
            
            % Enable/Disable the Spinners
            app.MaximumXEditField.Enable = isAuto;
            app.MaximumYEditField.Enable = isAuto;
            
            % Enable/Disable the Bx & By Tables
            if isAuto
                app.bXTable.Enable = 'off';
                app.bYTable.Enable = 'off';
            else
                app.bXTable.Enable = 'on';
                app.bYTable.Enable = 'on';
            end
            
        end
        
        function autoGenBxy(app)
            
            dim = app.SquareMatrixSizeSpinner.Value;
            
            % Obtain the desired maximum values
            maxX = app.MaximumXEditField.Value;
            maxY = app.MaximumYEditField.Value;
            
            % Determine divisors
            divX = maxX / (dim - 1);
            divY = maxY / (dim - 1);
            
            % Generate the matrices
            %app.bX = zeros(dim);
            xVals = 0:divX:maxX;
            %app.bX(1:end, :) = xVals;
            app.bX = repmat(xVals,dim,1);
            
            %app.bY = zeros(dim);
            yVals = (0:divY:maxY)';
            %app.bY(:, 1:end) = ;
            app.bY = repmat(yVals,1,dim);
            
            % Update Tables
            updateBTableDimensions(app);
            
        end
        
        function valid = validateImportedBezierSpreadsheet(app, bX, bY, bZ)
        % Validates that the Imported Bezier data is of the correct
        % dimensions
        
            % Returned Flag
            valid = 1;
            
            % Get Size of each Matrix
            bXSize = size(bX);
            bYSize = size(bY);
            bZSize = size(bZ);
            
            % If dimensions are not the same for all three, throw
            % exception
            if ~isequal(bXSize, bYSize, bZSize)
                valid = 0;
            
            % If the dimensions are the same size, ensure they are square
            elseif bXSize(1,1) ~= bXSize(1,2)
                valid = 0;
            end
            
            if ~valid
                excp = MException("The dimensions of the B matrices are not the same size.");
                throw(excp);
            end
            
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dummy)
            
            % Set the Step 1 Panel as the Task Panel
            app.DefineBezierCurvePanel.Parent = app.taskPanel;
            
            % Generate the Bx By arrays and create Bz
            autoGenBxy(app);
            app.bZTable.Data = app.bZ;
            
        end

        % Button pushed function: Step1Button
        function Step1ButtonPushed(app, event)
            
            % Set the Active Button Colour
            app.updateStepControlColour();
            app.Step1Button.BackgroundColor = app.activeStepButtonColour;
            
            % If moving from the Slicer View, update Preview with STL
%             if (app.SlicePanel.Parent == app.taskPanel)
%                 app.updateImportPreview();
%             end
            
            % Update the Side Panel
            app.removeChildPanel();
            app.DefineBezierCurvePanel.Parent = app.taskPanel;
            
        end

        % Button pushed function: Step2Button
        function Step2ButtonPushed(app, event)
            
            % Set the Active Button Colour
            app.updateStepControlColour();
            app.Step2Button.BackgroundColor = app.activeStepButtonColour;
            
            % If moving from the Slicer View, update Preview with STL
%             if (app.SlicePanel.Parent == app.taskPanel)
%                 app.updateImportPreview();
%             end
            
            % Update the Side Panel
            app.removeChildPanel();
            app.TriangulatePanel.Parent = app.taskPanel;
            
        end

        % Button pushed function: Step4Button
        function Step4ButtonPushed(app, event)
            
            % Set the Active Button Colour
            app.updateStepControlColour();
            app.Step4Button.BackgroundColor = app.activeStepButtonColour;
            
            % Update the Side Panel
            app.removeChildPanel();
            app.SlicePanel.Parent = app.taskPanel;
            app.updateSliceViewControls(-1);
            
            maxZ = app.LayerViewSlider.Value;
            
            % If a slicer path is available, show it in the preview.
            % Otherwise, clear
%             if height(app.slicerPath) == 0
%                 cla(app.previewAxis);
%             else
%                 LayerViewSliderValueChanged(app,event);
%             end
            
        end

        % Button pushed function: GoToStep2Button
        function GoToStep2ButtonPushed(app, event)
            Step2ButtonPushed(app,event);
        end

        % Button pushed function: GotoStep2TriangulateButton
        function GotoStep2TriangulateButtonPushed(app, event)
            Step2ButtonPushed(app,event);
        end

        % Button pushed function: GotoStep3Button
        function GotoStep3ButtonPushed(app, event)
            Step3ButtonPushed(app,event);
        end

        % Callback function
        function LayerThicknessmmSpinnerValueChanged(app, event)
            app.layerThickness = app.LayerThicknessmmSpinner.Value;
        end

        % Button pushed function: SliceModelButton
        function SliceModelButtonPushed(app, event)
            
            % Show progress bar
            progressBar = uiprogressdlg(app.Module4CurvedLayerSlicingUIFigure, 'Title','Please Wait','Message','Slicing (this might take a moment)','Indeterminate','on');
            
            % Generate Raster Path
            app.slicerPath = Layer_Builder(app.pX,app.pY,app.pZ,app.numLayers,app.layerThickness);
            
            % Update the plot with a preview of the slicer paths
            progressBar.Message = "Drawing slicer path preview (this might take a moment)";
            app.updateSlicerPathPreview(-1, 0);
            
            % Update the Slicer Preview Bar (with a pause for updating the
            % UI)
            progressBar.Message = "Updating UI (this might take a moment)";
            pause(1);
            [~, zHeights] = groupcounts(app.slicerPath(:,4));
            
            
            app.updateSliceViewControls(-1);
            
            maxLayer = height(zHeights);
            app.LayerViewNumberLabel.Text = string(maxLayer);
            if maxLayer > 1
                app.LayerViewSlider.Limits = [1, maxLayer];
                app.LayerViewSlider.Enable = 1;
            else
                app.LayerViewSlider.Limits = [1, 2];
                app.LayerViewSlider.Enable = 0;
            end
            app.LayerViewSlider.Value = maxLayer;
            
            % Close progress bar
            close(progressBar);
            
        end

        % Callback function
        function ApplyTransformstoModelButtonPushed(app, event)
            
            % Show progress bar
            progressBar = uiprogressdlg(app.Module4CurvedLayerSlicingUIFigure, 'Title','Please Wait','Message','Applying transforms','Indeterminate','on');
            
            % Clear any existing Slicer Path
            app.slicerPath = [];
            
            % Retrieve the Spinner Values for the Angle
            thetaX = app.RotatexdegreesSpinner.Value;
            thetaY = app.RotateydegreesSpinner.Value;
            thetaZ = app.RotatezdegreesSpinner.Value;
            
            % Retrieve the Spinner Values for the Translate Value
            dX = app.TranslateXSpinner.Value;
            dY = app.TranslateYSpinner.Value;
            dZ = app.TranslateZSpinner.Value;
            
            % Retrieve the Spinner Values for the Scale Value
            scale = app.ScaleSpinner.Value;
            
            % Pass to function
            app.stlModel = stlTransform(app.importedSTLModel, thetaX, thetaY, thetaZ, dX, dY, dZ, scale);
            
            % Calculate the new Plot Limits
            app.updatePlotLimits();
                
            % Update plot with the returned model
%             app.updateImportPreview();
            
            % Enable the reset button
            app.ClearTransformsButton.Enable = true;
            
            % Wait for window re-draw to complete and close progress bar
            pause(0.5);
            close(progressBar);
            
        end

        % Callback function
        function ClearTransformsButtonPushed(app, event)
            
            % Show progress bar
            progressBar = uiprogressdlg(app.Module4CurvedLayerSlicingUIFigure, 'Title','Please Wait','Message','Reverting back to imported model','Indeterminate','on');
            
            % Reset to intial model
            app.stlModel = app.importedSTLModel;
            
            % Clear any existing Slicer Path
            app.slicerPath = [];
            app.updatePlotLimits();
            
            % Update Plot
%             app.updateImportPreview();
%             
            % Disable the reset button
            app.ClearTransformsButton.Enable = false;
            
            % Clear all checkboxes
            app.RotatethisModelCheckBox.Value = 0;
            app.TranslatethisModelCheckBox.Value = 0;
            app.TranslatethisModelCheckBox.Enable = 1;
            
            % Clear all spinners
            app.RotatexdegreesSpinner.Enable = false;
            app.RotateydegreesSpinner.Enable = false;
            app.RotatezdegreesSpinner.Enable = false;
            
            app.RotatexdegreesSpinner.Value = 0;
            app.RotateydegreesSpinner.Value = 0;
            app.RotatezdegreesSpinner.Value = 0;
            
            app.TranslateXSpinner.Enable = false;
            app.TranslateYSpinner.Enable = false;
            app.TranslateZSpinner.Enable = false;
            
            app.TranslateXSpinner.Value = 0;
            app.TranslateYSpinner.Value = 0;
            app.TranslateZSpinner.Value = 0;
            
            app.ScaleSpinner.Value = 100.0;
            
            % Disable the Apply Button
            app.ApplyTransformstoModelButton.Enable = false;

            % Close progress bar
            close(progressBar);
            
        end

        % Callback function
        function RotatethisModelCheckBoxValueChanged(app, event)
            value = app.RotatethisModelCheckBox.Value;
            
            if value == 0
                
                app.RotatexdegreesSpinner.Enable = false;
                app.RotateydegreesSpinner.Enable = false;
                app.RotatezdegreesSpinner.Enable = false;
                
                app.RotatexdegreesSpinner.Value = 0;
                app.RotateydegreesSpinner.Value = 0;
                app.RotatezdegreesSpinner.Value = 0;
                
            else
                
                app.RotatexdegreesSpinner.Enable = true;
                app.RotateydegreesSpinner.Enable = true;
                app.RotatezdegreesSpinner.Enable = true;
                
            end
            
            updateTransformMasterButtons(app, event);
            
        end

        % Callback function
        function TranslatethisModelCheckBoxValueChanged(app, event)
            value = app.TranslatethisModelCheckBox.Value;
            
            if value == 0
                
                app.TranslateXSpinner.Enable = false;
                app.TranslateYSpinner.Enable = false;
                app.TranslateZSpinner.Enable = false;
                
                app.TranslateXSpinner.Value = 0;
                app.TranslateYSpinner.Value = 0;
                app.TranslateZSpinner.Value = 0;
                
            else
                
                app.TranslateXSpinner.Enable = true;
                app.TranslateYSpinner.Enable = true;
                app.TranslateZSpinner.Enable = true;
                
            end
            
            updateTransformMasterButtons(app, event);
        end

        % Callback function
        function ScaleSpinnerValueChanged(app, event)
            updateTransformMasterButtons(app, event);
        end

        % Value changed function: LayerViewSlider
        function LayerViewSliderValueChanged(app, event)
            
            % Disable the slider
            app.LayerViewSlider.Enable = false;
            
            % Show progress bar
            progressBar = uiprogressdlg(app.Module4CurvedLayerSlicingUIFigure, 'Title','Please Wait','Message','Updating slicer path preview (this might take a moment)','Indeterminate','on');
                        
            % Get the value and re-draw
            value = round(app.LayerViewSlider.Value);
            isolate = app.IsolateLayerinPreviewCheckBox.Value;
            app.updateSlicerPathPreview(value, isolate);
            
            % Close Progress Bar
            pause(1);
            close(progressBar);
            
            % Re-enable the slider
            app.LayerViewSlider.Enable = true;
            
        end

        % Value changing function: LayerViewSlider
        function LayerViewSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.LayerViewNumberLabel.Text = string(changingValue);
        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)
            
            % If an animiation is currently running, set the flag to stop
            % it
            if (app.isAnimating)
                app.AnimateButton.Text = "Stopping...";
                app.isAnimating = 0;
                
            % Otherwise, start animation
            else
                
                if app.AnimateuptoSelectedSliceButton.Value == 1
                    isolate = 0;
                    selectedLayer = round(app.LayerViewSlider.Value);
                elseif app.AnimateSelectedSliceButton.Value == 1
                    isolate = 1;
                    selectedLayer = round(app.LayerViewSlider.Value);
                else
                    isolate = 0;
                    selectedLayer = -1;
                end
                
                animationSpeed = app.AnimationSpeedSpinner.Value;
                app.animateSlicerPathPreview(selectedLayer, animationSpeed, isolate);
                
            end
            
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            
            % Show progress bar
            progressBar = uiprogressdlg(app.Module4CurvedLayerSlicingUIFigure, 'Title','Please Wait','Message','Waiting for user to select file location...','Indeterminate','on');
            
            try
              fileTypes = {'*.csv', 'CSV File'};
                [fileName, filePath] = uiputfile(fileTypes, 'Export to CSV File...');
                addpath(filePath);
                progressBar.Message = "Exporting data";
                writematrix(app.slicerPath, filePath + "\" + fileName);
                helpdlg("The file has been successfully exported.");
                close(progressBar);
            catch exception
                errordlg("There was an issue exporting a .csv file: " + exception.message);
                close(progressBar);
                return;
            end
            
        end

        % Button pushed function: ResetViewButton
        function ResetViewButtonPushed(app, event)
            
            % Reset the Values of the Slider and Checkbox
            app.IsolateLayerinPreviewCheckBox.Value = 0;
            app.LayerViewSlider.Value = app.LayerViewSlider.Limits(1,2);
            app.AnimationSpeedSpinner.Value = app.defaultAnimationSpeed;
            app.LayerViewNumberLabel.Text = string(app.LayerViewSlider.Value);
            
            % Use the Callback for the Slider Change to redraw the plot
            app.LayerViewSliderValueChanged(event);
            
        end

        % Value changed function: IsolateLayerinPreviewCheckBox
        function IsolateLayerinPreviewCheckBoxValueChanged(app, event)
            
            % Call the Layer Slider Changed Callback to update the plot
            app.LayerViewSliderValueChanged(event);
            
        end

        % Callback function
        function SlicerSelectionRadioGroupSelectionChanged(app, event)
            app.slicerPath = [];
            app.LayerThicknessmmSpinner.Enable = app.UniformSlicingButton.Value;
        end

        % Value changed function: SquareMatrixSizeSpinner
        function SquareMatrixSizeSpinnerValueChanged(app, event)
            clearPreview(app)
            value = app.SquareMatrixSizeSpinner.Value;
            app.updateBTableDimensions();
        end

        % Cell edit callback: bZTable
        function bZTableCellEdit(app, event)
            
            clearPreview(app)
            
            indices = event.Indices;
            newData = event.NewData;
            i = indices(:,1);
            j = indices(:,2);
            
            if ~isnan(newData) && isnumeric(newData)
                app.bZ(i,j) = newData;
            else
                app.bZTable.Data = app.bZ;
            end
            
        end

        % Selection changed function: AutoGenXYRadioGroup
        function AutoGenXYRadioGroupSelectionChanged(app, event)
            
            % Update the Panel Options & Step Buttons based on the Radio Selection
            clearPreview(app)
            app.updateAutoGenerateBxy();
            
        end

        % Value changed function: MaximumYEditField
        function MaximumYEditFieldValueChanged(app, event)
            
            % Generate the Bx and By matrices automatically
            clearPreview(app)
            autoGenBxy(app);
            
        end

        % Value changed function: MaximumXEditField
        function MaximumXEditFieldValueChanged(app, event)

            % Generate the Bx and By matrices automatically
            clearPreview(app)
            autoGenBxy(app);
            
        end

        % Button pushed function: GenerateTriangulationButton
        function GenerateTriangulationButtonPushed(app, event)
            
            % Triangulate
            [app.pX, app.pY, app.pZ] = GenerateBezierCurve(app.bX,app.bY,app.bZ,app.triangRes);
            app.bezierTriangulation = tri(app.pX, app.pY, app.pZ);
            
            % Plot
            updateBezierTriangulationPreview(app);
            
            % Enable Export Button and Step 3
            app.ExportTriangulationToXlsxButton.Enable = 1;
            app.ExportTriangulationToSTLButton.Enable = 1;
            app.GotoStep3Button.Enable = 1;
            app.updateStepControls(4);
            
        end

        % Value changed function: ResolutionSpinner
        function ResolutionSpinnerValueChanged(app, event)
            app.triangRes = app.ResolutionSpinner.Value;
        end

        % Button pushed function: SwitchToBxByPanelButton
        function SwitchToBxByPanelButtonPushed(app, event)
            
            % Hide the Bx By Warning Label
            app.BxyWarningLabel.Visible = false;
            
            % Enable the Step 2 Button
            app.GoToStep2Button.Enable = 1;
            
            % Switch to the Bx By Panel
            app.removeChildPanel();
            app.DefineBxByPanel.Parent = app.taskPanel;
            
        end

        % Button pushed function: SwitchToBezierDefinePanelButton
        function SwitchToBezierDefinePanelButtonPushed(app, event)
            app.removeChildPanel();
            app.DefineBezierCurvePanel.Parent = app.taskPanel;
        end

        % Button pushed function: ExportTriangulationToXlsxButton
        function ExportTriangulationToXlsxButtonPushed(app, event)
            
            % Show progress bar
            progressBar = uiprogressdlg(app.Module4CurvedLayerSlicingUIFigure, 'Title','Please Wait','Message','Waiting for user to select file location...','Indeterminate','on');
            
            try
                fileTypes = {'*.xlsx', 'Excel Spreadsheet'};
                [fileName, filePath] = uiputfile(fileTypes, 'Export to Excel Spreadsheet...');
                addpath(filePath);
                progressBar.Message = "Exporting data";
                writematrix(app.bezierTriangulation.ConnectivityList, filePath + "\" + fileName, 'Sheet',1);
                writematrix(app.bezierTriangulation.Points, filePath + "\" + fileName, 'Sheet',1);
                writematrix(app.slicerPath, filePath + "\" + fileName, 'Sheet',1);
                helpdlg("The file has been successfully exported.");
                close(progressBar);
            catch exception
                errordlg("There was an issue exporting a Excel Spreadsheet: " + exception.message + ". This error might occur if you cancel the save prompt, or if you select a file location that isn't accessible on your computer.");
                close(progressBar);
                return;
            end
            
        end

        % Button pushed function: ExportTriangulationToSTLButton
        function ExportTriangulationToSTLButtonPushed(app, event)
            
            % Show progress bar
            progressBar = uiprogressdlg(app.Module4CurvedLayerSlicingUIFigure, 'Title','Please Wait','Message','Waiting for user to select file location...','Indeterminate','on');
            
            try
                fileTypes = {'*.stl', 'STL File'};
                [fileName, filePath] = uiputfile(fileTypes, 'Export to STL...');
                addpath(filePath);
                progressBar.Message = "Exporting data";
                stlwrite(app.bezierTriangulation, filePath + "\" + fileName);
                helpdlg("The file has been successfully exported.");
                close(progressBar);
            catch exception
                errordlg("There was an issue exporting a .stl file: " + exception.message + ". This error might occur if you cancel the save prompt, or if you select a file location that isn't accessible on your computer.");
                close(progressBar);
                return;
            end
            
        end

        % Cell edit callback: bXTable
        function bXTableCellEdit(app, event)
            
            clearPreview(app);
            
            indices = event.Indices;
            newData = event.NewData;
            i = indices(:,1);
            j = indices(:,2);
            
            if ~isnan(newData) && isnumeric(newData)
                app.bX(i,j) = newData;
            else
                app.bXTable.Data = app.bX;
            end
            
        end

        % Cell edit callback: bYTable
        function bYTableCellEdit(app, event)
            
            clearPreview(app);
            
            indices = event.Indices;
            newData = event.NewData;
            i = indices(:,1);
            j = indices(:,2);
            
            if ~isnan(newData) && isnumeric(newData)
                app.bY(i,j) = newData;
            else
                app.bYTable.Data = app.bY;
            end
            
        end

        % Button pushed function: ImportBezierBMatrixButton
        function ImportBezierBMatrixButtonPushed(app, event)
            
            % Show progress bar
            progressBar = uiprogressdlg(app.Module4CurvedLayerSlicingUIFigure, 'Title','Please Wait','Message','Waiting for user file selection...','Indeterminate','on');
            
            try
                % Attempt to open the STL File
                fileTypes = {'*.xlsx', 'Excel Spreadsheet'};
                [file, path] = uigetfile(fileTypes, 'Import Bezier Curve from Excel Spreadsheet...');
                
                % Add PATH to file to MATLAB's path
                addpath(path);
                
                % Extract the B matrices from the Spreadsheet
                progressBar.Message = "Importing Bezier Curve (this might take a moment)";
                fullPath = strcat(path,'\',file);
                importedBx = readmatrix(fullPath,'Sheet','Bx');
                importedBy = readmatrix(fullPath,'Sheet','By');
                importedBz = readmatrix(fullPath,'Sheet','Bz');
                
                % Validate the Bezier Curve Data - throws an exception if
                % the data is not valid and breaks the function.
                progressBar.Message = "Validating Bezier Curve (this might take a moment)";
                app.validateImportedBezierSpreadsheet(importedBx, importedBy, importedBz);
                
                % Update the dimensions and controls for the B matrices
                app.SquareMatrixSizeSpinner.Value = height(importedBx);
                app.ManuallyGenerateBxByButton.Value = 1;
                app.updateAutoGenerateBxy();
                dimensions = app.SquareMatrixSizeSpinner.Value;
                app.updateBTableDimensions();
                
                % Write the data to the Bx, By, Bz Matrices
                app.bX = importedBx;
                app.bY = importedBy;
                app.bZ = importedBz;
                
                % Update tables
                app.updateTable(app.bXTable, app.bX, dimensions);
                app.updateTable(app.bYTable, app.bY, dimensions);
                app.updateTable(app.bZTable, app.bZ, dimensions);
                
                % Hide the Bx By Warning Label
                app.BxyWarningLabel.Visible = false;
                
                % Enable the Step 2 Button
                app.GoToStep2Button.Enable = 1;
                
            catch exception
                
                % Display the Exception as an Error Dialog and close the
                % progress bar
                close(progressBar);
                errordlg("There was an error importing the Excel Spreadsheet: " + exception.message + ". This is usually an issue with the Excel Spreadsheet or the Bx, By, Bz sheet data.");
                
            end
            
            % Close progress bar
            close(progressBar);
        end

        % Button pushed function: Step3Button
        function Step3ButtonPushed(app, event)
            % Set the Active Button Colour
            app.updateStepControlColour();
            app.Step3Button.BackgroundColor = app.activeStepButtonColour;
            
            % If moving from the Slicer View, update Preview with STL
%             if (app.SlicePanel.Parent == app.taskPanel)
%                 app.updateImportPreview();
%             end
            
            % Update the Side Panel
            app.removeChildPanel();
            app.ConfigurePanel.Parent = app.taskPanel;
        end

        % Value changed function: NumLayerSpinner
        function NumLayerSpinnerValueChanged(app, event)
            app.numLayers = app.NumLayerSpinner.Value;
        end

        % Value changed function: LayerThicknessSpinner
        function LayerThicknessSpinnerValueChanged(app, event)
            app.layerThickness = app.LayerThicknessSpinner.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Module4CurvedLayerSlicingUIFigure = uifigure('Visible', 'off');
            app.Module4CurvedLayerSlicingUIFigure.Name = 'Module 4: Curved Layer Slicing';
            app.Module4CurvedLayerSlicingUIFigure.Icon = 'img_pathSelection_22px.png';
            app.GridLayout = uigridlayout(app.Module4CurvedLayerSlicingUIFigure);
            app.StepButtonGrid = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.StepButtonGrid);
            app.Step1Button = uibutton(app.GridLayout4, 'push');
            app.Step1Button.ButtonPushedFcn = createCallbackFcn(app, @Step1ButtonPushed, true);
            app.Step1Button.Icon = 'img_bezier_32px.png';
            app.Step1Button.Text = '1. Define Bezier';
            app.Step2Button = uibutton(app.GridLayout4, 'push');
            app.Step2Button.ButtonPushedFcn = createCallbackFcn(app, @Step2ButtonPushed, true);
            app.Step2Button.Icon = 'img_triangulate_32px.png';
            app.Step2Button.Text = '2. Triangulate';
            app.Step4Button = uibutton(app.GridLayout4, 'push');
            app.Step4Button.ButtonPushedFcn = createCallbackFcn(app, @Step4ButtonPushed, true);
            app.Step4Button.Icon = 'img_slice_32px.png';
            app.Step4Button.Enable = 'off';
            app.Step4Button.Text = '4. Slice';
            app.Step3Button = uibutton(app.GridLayout4, 'push');
            app.Step3Button.ButtonPushedFcn = createCallbackFcn(app, @Step3ButtonPushed, true);
            app.Step3Button.Icon = 'img_configure_32px.png';
            app.Step3Button.Enable = 'off';
            app.Step3Button.Text = '3. Configure';
            app.MainViewsGrid = uigridlayout(app.GridLayout);
            app.taskPanel = uigridlayout(app.MainViewsGrid);
            app.NoModelLabel = uilabel(app.MainViewsGrid);
            app.NoModelLabel.Text = {'Curve Not Generated. '; 'Complete Steps 1 & 2, and select "Generate Curve" to see a preview here.'};
            app.previewAxis = uiaxes(app.MainViewsGrid);
            xlabel(app.previewAxis, 'X')
            ylabel(app.previewAxis, 'Y')
            zlabel(app.previewAxis, 'Z')
            app.previewAxis.Visible = 'off';
            app.DefineBxByPanel = uipanel(app.Module4CurvedLayerSlicingUIFigure);
            app.DefineBxByPanel.Title = 'Define Bx By';
            app.TransformPanelGridLayout = uigridlayout(app.DefineBxByPanel);
            app.GridLayout22 = uigridlayout(app.TransformPanelGridLayout);
            app.GridLayout21_2 = uigridlayout(app.GridLayout22);
            app.Image12_2 = uiimage(app.GridLayout21_2);
            app.Image12_2.ImageSource = 'img_table_22px.png';
            app.DefineBxLabel = uilabel(app.GridLayout21_2);
            app.DefineBxLabel.Text = 'Define Bx';
            app.bXTable = uitable(app.GridLayout22);
            app.bXTable.ColumnName = '';
            app.bXTable.RowName = {};
            app.bXTable.CellEditCallback = createCallbackFcn(app, @bXTableCellEdit, true);
            app.bXTable.Enable = 'off';
            app.GridLayout23 = uigridlayout(app.TransformPanelGridLayout);
            app.GridLayout22_2 = uigridlayout(app.GridLayout23);
            app.GridLayout21_3 = uigridlayout(app.GridLayout22_2);
            app.Image12_3 = uiimage(app.GridLayout21_3);
            app.Image12_3.ImageSource = 'img_table_22px.png';
            app.DefineByLabel = uilabel(app.GridLayout21_3);
            app.DefineByLabel.Text = 'Define By';
            app.bYTable = uitable(app.GridLayout23);
            app.bYTable.ColumnName = '';
            app.bYTable.RowName = {};
            app.bYTable.CellEditCallback = createCallbackFcn(app, @bYTableCellEdit, true);
            app.bYTable.Enable = 'off';
            app.AutoGenXYRadios = uigridlayout(app.TransformPanelGridLayout);
            app.GridLayout20 = uigridlayout(app.AutoGenXYRadios);
            app.Image8 = uiimage(app.GridLayout20);
            app.Image8.ImageSource = 'img_greenWand_22px.png';
            app.BxByGenerationLabel = uilabel(app.GridLayout20);
            app.BxByGenerationLabel.Text = 'Bx & By Generation';
            app.AutoGenXYRadioGroup = uibuttongroup(app.AutoGenXYRadios);
            app.AutoGenXYRadioGroup.SelectionChangedFcn = createCallbackFcn(app, @AutoGenXYRadioGroupSelectionChanged, true);
            app.ManuallyGenerateBxByButton = uiradiobutton(app.AutoGenXYRadioGroup);
            app.ManuallyGenerateBxByButton.Text = 'Manually Generate Bx & By';
            app.AutoGenerateUniformBxByPointsButton = uiradiobutton(app.AutoGenXYRadioGroup);
            app.AutoGenerateUniformBxByPointsButton.Text = 'Auto-Generate Uniform Bx & By Points';
            app.AutoGenerateUniformBxByPointsButton.Value = true;
            app.Label_3 = uilabel(app.AutoGenXYRadios);
            app.Label_3.Text = 'Auto-Generate generates uniform Bx, By points between 0 and the maximum values.';
            app.Panel_3 = uipanel(app.AutoGenXYRadios);
            app.MaximumYEditFieldLabel = uilabel(app.Panel_3);
            app.MaximumYEditFieldLabel.Text = 'Maximum Y';
            app.MaximumYEditField = uieditfield(app.Panel_3, 'numeric');
            app.MaximumYEditField.Limits = [0.01 Inf];
            app.MaximumYEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumYEditFieldValueChanged, true);
            app.MaximumYEditField.Value = 10;
            app.MaximumXEditFieldLabel = uilabel(app.Panel_3);
            app.MaximumXEditFieldLabel.Text = 'Maximum X';
            app.MaximumXEditField = uieditfield(app.Panel_3, 'numeric');
            app.MaximumXEditField.Limits = [0.01 Inf];
            app.MaximumXEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumXEditFieldValueChanged, true);
            app.MaximumXEditField.Value = 10;
            app.GotoStep2TriangulateButton = uibutton(app.TransformPanelGridLayout, 'push');
            app.GotoStep2TriangulateButton.ButtonPushedFcn = createCallbackFcn(app, @GotoStep2TriangulateButtonPushed, true);
            app.GotoStep2TriangulateButton.Icon = 'img_arrowRight_16px.png';
            app.GotoStep2TriangulateButton.Text = 'Go to Step 2: Triangulate';
            app.SwitchToBezierDefinePanelButton = uibutton(app.TransformPanelGridLayout, 'push');
            app.SwitchToBezierDefinePanelButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchToBezierDefinePanelButtonPushed, true);
            app.SwitchToBezierDefinePanelButton.Icon = 'img_bezier_32px.png';
            app.SwitchToBezierDefinePanelButton.Text = 'Define Matrix Size & Bz';
            app.TriangulatePanel = uipanel(app.Module4CurvedLayerSlicingUIFigure);
            app.TriangulatePanel.Title = 'Triangulate';
            app.GridLayout5_3 = uigridlayout(app.TriangulatePanel);
            app.SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel_5 = uilabel(app.GridLayout5_3);
            app.SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel_5.Text = 'Configure the resolution for triangulation and select "Generate Triangulation".';
            app.GotoStep3Button = uibutton(app.GridLayout5_3, 'push');
            app.GotoStep3Button.ButtonPushedFcn = createCallbackFcn(app, @GotoStep3ButtonPushed, true);
            app.GotoStep3Button.Icon = 'img_arrowRight_16px.png';
            app.GotoStep3Button.Enable = 'off';
            app.GotoStep3Button.Text = 'Go to Step 3: Slice';
            app.TriangulationResolutionLayout = uigridlayout(app.GridLayout5_3);
            app.TriangulationHero = uigridlayout(app.TriangulationResolutionLayout);
            app.Image13 = uiimage(app.TriangulationHero);
            app.Image13.ImageSource = 'img_stlRes_32px.png';
            app.TriangulationResolutionLabel = uilabel(app.TriangulationHero);
            app.TriangulationResolutionLabel.Text = 'Triangulation Resolution';
            app.ResolutionDescriptionLabel = uilabel(app.TriangulationResolutionLayout);
            app.ResolutionDescriptionLabel.Text = 'A higher triangulation resolution will generate a smoother curve, but at the expense of storage and compute time.';
            app.ResolutionSpinnerPanel = uipanel(app.TriangulationResolutionLayout);
            app.ResolutionSpinnerLabel = uilabel(app.ResolutionSpinnerPanel);
            app.ResolutionSpinnerLabel.Text = 'Resolution';
            app.ResolutionSpinner = uispinner(app.ResolutionSpinnerPanel);
            app.ResolutionSpinner.Limits = [2 Inf];
            app.ResolutionSpinner.ValueChangedFcn = createCallbackFcn(app, @ResolutionSpinnerValueChanged, true);
            app.ResolutionSpinner.Value = 20;
            app.GenerateTriangulationButton = uibutton(app.GridLayout5_3, 'push');
            app.GenerateTriangulationButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTriangulationButtonPushed, true);
            app.GenerateTriangulationButton.Icon = 'img_run_22px.png';
            app.GenerateTriangulationButton.Text = 'Generate Triangulation';
            app.GridLayout24 = uigridlayout(app.GridLayout5_3);
            app.ExportTriangulationToXlsxButton = uibutton(app.GridLayout24, 'push');
            app.ExportTriangulationToXlsxButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTriangulationToXlsxButtonPushed, true);
            app.ExportTriangulationToXlsxButton.Icon = 'img_save_22px.png';
            app.ExportTriangulationToXlsxButton.Enable = 'off';
            app.ExportTriangulationToXlsxButton.Tooltip = {'Export the last Generated Triangulation as Excel Spreadsheet, containinig two sheets - a Connectivity List, and a set of Points (Vertices).'};
            app.ExportTriangulationToXlsxButton.Text = {'Save Excel'; ''; ''};
            app.ExportTriangulationToSTLButton = uibutton(app.GridLayout24, 'push');
            app.ExportTriangulationToSTLButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTriangulationToSTLButtonPushed, true);
            app.ExportTriangulationToSTLButton.Icon = 'img_save_22px.png';
            app.ExportTriangulationToSTLButton.Enable = 'off';
            app.ExportTriangulationToSTLButton.Tooltip = {'Export the last Generated Triangulation as an STL file.'};
            app.ExportTriangulationToSTLButton.Text = {'Save STL'; ''; ''};
            app.SlicePanel = uipanel(app.Module4CurvedLayerSlicingUIFigure);
            app.SlicePanel.Title = 'Slice';
            app.GridLayout5_4 = uigridlayout(app.SlicePanel);
            app.GridLayout6_4 = uigridlayout(app.GridLayout5_4);
            app.ResetViewButton = uibutton(app.GridLayout6_4, 'push');
            app.ResetViewButton.ButtonPushedFcn = createCallbackFcn(app, @ResetViewButtonPushed, true);
            app.ResetViewButton.Icon = 'img_undo_22px.png';
            app.ResetViewButton.Enable = 'off';
            app.ResetViewButton.Text = 'Reset View';
            app.ExportDataButton = uibutton(app.GridLayout6_4, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Icon = 'img_save_22px.png';
            app.ExportDataButton.Enable = 'off';
            app.ExportDataButton.Text = 'Export to .csv...';
            app.SelectedFileNameLabel_4 = uilabel(app.GridLayout5_4);
            app.SelectedFileNameLabel_4.Text = 'Slicer is Ready';
            app.SliceModelButton = uibutton(app.GridLayout5_4, 'push');
            app.SliceModelButton.ButtonPushedFcn = createCallbackFcn(app, @SliceModelButtonPushed, true);
            app.SliceModelButton.Icon = 'img_run_22px.png';
            app.SliceModelButton.Text = 'Slice Model';
            app.GridLayout16 = uigridlayout(app.GridLayout5_4);
            app.GridLayout17 = uigridlayout(app.GridLayout16);
            app.ExploreLayersLabel = uilabel(app.GridLayout17);
            app.ExploreLayersLabel.Text = 'Explore Layers';
            app.Image5 = uiimage(app.GridLayout17);
            app.Image5.ImageSource = 'img_find_22px.png';
            app.Panel_2 = uipanel(app.GridLayout16);
            app.LayerLabel = uilabel(app.Panel_2);
            app.LayerLabel.Enable = 'off';
            app.LayerLabel.Text = 'Layer';
            app.LayerViewSlider = uislider(app.Panel_2);
            app.LayerViewSlider.Limits = [1 2];
            app.LayerViewSlider.ValueChangedFcn = createCallbackFcn(app, @LayerViewSliderValueChanged, true);
            app.LayerViewSlider.ValueChangingFcn = createCallbackFcn(app, @LayerViewSliderValueChanging, true);
            app.LayerViewSlider.Enable = 'off';
            app.LayerViewSlider.Value = 1;
            app.LayerViewNumberLabel = uilabel(app.Panel_2);
            app.LayerViewNumberLabel.Text = {'1'; ''};
            app.IsolateLayerinPreviewCheckBox = uicheckbox(app.GridLayout16);
            app.IsolateLayerinPreviewCheckBox.ValueChangedFcn = createCallbackFcn(app, @IsolateLayerinPreviewCheckBoxValueChanged, true);
            app.IsolateLayerinPreviewCheckBox.Enable = 'off';
            app.IsolateLayerinPreviewCheckBox.Text = 'Isolate Layer in Preview';
            app.SliceHeroLabel = uilabel(app.GridLayout5_4);
            app.SliceHeroLabel.Text = 'Select "Slice Model" to update the Slicer Path, then use the tools to view and export the Slicer Path.';
            app.AnimationGrid = uigridlayout(app.GridLayout5_4);
            app.AnimationHero = uigridlayout(app.AnimationGrid);
            app.AnimationTitle = uilabel(app.AnimationHero);
            app.AnimationTitle.Text = 'Animate';
            app.AnimationImg = uiimage(app.AnimationHero);
            app.AnimationImg.ImageSource = 'img_greenWand_22px.png';
            app.AnimationSpeedPanel = uipanel(app.AnimationGrid);
            app.StepSpeedsecLabel = uilabel(app.AnimationSpeedPanel);
            app.StepSpeedsecLabel.Enable = 'off';
            app.StepSpeedsecLabel.Text = 'Step Speed (sec)';
            app.AnimationSpeedSpinner = uispinner(app.AnimationSpeedPanel);
            app.AnimationSpeedSpinner.Step = 0.1;
            app.AnimationSpeedSpinner.Limits = [0.1 Inf];
            app.AnimationSpeedSpinner.Enable = 'off';
            app.AnimationSpeedSpinner.Value = 0.5;
            app.AnimateButton = uibutton(app.AnimationGrid, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Icon = 'img_animate_22px.png';
            app.AnimateButton.Enable = 'off';
            app.AnimateButton.Text = 'Animate...';
            app.AnimationOptionRadioControlGroup = uibuttongroup(app.AnimationGrid);
            app.AnimateSelectedSliceButton = uiradiobutton(app.AnimationOptionRadioControlGroup);
            app.AnimateSelectedSliceButton.Enable = 'off';
            app.AnimateSelectedSliceButton.Text = 'Animate Selected Slice';
            app.AnimateSelectedSliceButton.Value = true;
            app.AnimateuptoSelectedSliceButton = uiradiobutton(app.AnimationOptionRadioControlGroup);
            app.AnimateuptoSelectedSliceButton.Enable = 'off';
            app.AnimateuptoSelectedSliceButton.Text = 'Animate up to Selected Slice';
            app.AnimateentireModelButton = uiradiobutton(app.AnimationOptionRadioControlGroup);
            app.AnimateentireModelButton.Enable = 'off';
            app.AnimateentireModelButton.Text = 'Animate entire Model';
            app.RasteriserOptions = uipanel(app.GridLayout5_4);
            app.DefineBezierCurvePanel = uipanel(app.Module4CurvedLayerSlicingUIFigure);
            app.DefineBezierCurvePanel.Title = 'Define Bezier Curve';
            app.DefinePanelLayout = uigridlayout(app.DefineBezierCurvePanel);
            app.DefinethepropertiesoftheBezierCurvetogenerateLabel = uilabel(app.DefinePanelLayout);
            app.DefinethepropertiesoftheBezierCurvetogenerateLabel.Text = 'Define the properties of the Bezier Curve to generate.';
            app.bZTableLayout = uigridlayout(app.DefinePanelLayout);
            app.GridLayout21 = uigridlayout(app.bZTableLayout);
            app.Image12 = uiimage(app.GridLayout21);
            app.Image12.ImageSource = 'img_table_22px.png';
            app.DefineBzLabel = uilabel(app.GridLayout21);
            app.DefineBzLabel.Text = 'Define Bz';
            app.bZTable = uitable(app.bZTableLayout);
            app.bZTable.ColumnName = '';
            app.bZTable.RowName = {};
            app.bZTable.CellEditCallback = createCallbackFcn(app, @bZTableCellEdit, true);
            app.SquareMatrixSizeSpinnerPanel = uipanel(app.DefinePanelLayout);
            app.SquareMatrixSizeSpinnerLabel = uilabel(app.SquareMatrixSizeSpinnerPanel);
            app.SquareMatrixSizeSpinnerLabel.Text = 'Square Matrix Size';
            app.SquareMatrixSizeSpinner = uispinner(app.SquareMatrixSizeSpinnerPanel);
            app.SquareMatrixSizeSpinner.Limits = [2 Inf];
            app.SquareMatrixSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @SquareMatrixSizeSpinnerValueChanged, true);
            app.SquareMatrixSizeSpinner.Value = 5;
            app.SwitchToBxByPanelButton = uibutton(app.DefinePanelLayout, 'push');
            app.SwitchToBxByPanelButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchToBxByPanelButtonPushed, true);
            app.SwitchToBxByPanelButton.Icon = 'img_pathSelection_22px.png';
            app.SwitchToBxByPanelButton.Text = 'Define Bx & By';
            app.ImportBezierBMatrixButton = uibutton(app.DefinePanelLayout, 'push');
            app.ImportBezierBMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @ImportBezierBMatrixButtonPushed, true);
            app.ImportBezierBMatrixButton.Icon = 'img_open_22px.png';
            app.ImportBezierBMatrixButton.Text = 'Import Bezier from Spreadsheet...';
            app.GoToStep2Button = uibutton(app.DefinePanelLayout, 'push');
            app.GoToStep2Button.ButtonPushedFcn = createCallbackFcn(app, @GoToStep2ButtonPushed, true);
            app.GoToStep2Button.Icon = 'img_arrowRight_16px.png';
            app.GoToStep2Button.Enable = 'off';
            app.GoToStep2Button.Text = 'Go to Step 2: Triangulate';
            app.BxyWarningLabel = uilabel(app.DefinePanelLayout);
            app.BxyWarningLabel.Text = 'Bx & By not defined!';
            app.ConfigurePanel = uipanel(app.Module4CurvedLayerSlicingUIFigure);
            app.ConfigurePanel.Title = 'Configure';
            app.GridLayout25 = uigridlayout(app.ConfigurePanel);
            app.LayerThicknessPanel = uigridlayout(app.GridLayout25);
            app.LayerThicknessHero = uigridlayout(app.LayerThicknessPanel);
            app.LayerThicknessLabel = uilabel(app.LayerThicknessHero);
            app.LayerThicknessLabel.Text = 'Layer Thickness';
            app.Image14 = uiimage(app.LayerThicknessHero);
            app.Image14.ImageSource = 'img_layers_22px.png';
            app.Label_4 = uilabel(app.LayerThicknessPanel);
            app.Label_4.Text = 'Sets the layer thickness. A greater layer thickness results in less layers, but at the cost of lower resolution.';
            app.Panel_4 = uipanel(app.LayerThicknessPanel);
            app.LayerThicknessLabel_2 = uilabel(app.Panel_4);
            app.LayerThicknessLabel_2.Text = 'Layer Thickness';
            app.LayerThicknessSpinner = uispinner(app.Panel_4);
            app.LayerThicknessSpinner.Step = 0.1;
            app.LayerThicknessSpinner.Limits = [0.0001 Inf];
            app.LayerThicknessSpinner.ValueChangedFcn = createCallbackFcn(app, @LayerThicknessSpinnerValueChanged, true);
            app.LayerThicknessSpinner.Value = 0.1;
            app.NumberOfLayersLayout = uigridlayout(app.GridLayout25);
            app.NumberOfLayersHero = uigridlayout(app.NumberOfLayersLayout);
            app.Image15 = uiimage(app.NumberOfLayersHero);
            app.Image15.ImageSource = 'img_layers_22px.png';
            app.NumberofLayersLabel = uilabel(app.NumberOfLayersHero);
            app.NumberofLayersLabel.Text = 'Number of Layers';
            app.NumberOfLayersLabel = uilabel(app.NumberOfLayersLayout);
            app.NumberOfLayersLabel.Text = 'Set the number of desired layers. The thickness will be determined by the number of layers multiplied by the layer thickness.';
            app.NumberOfLayersPanel = uipanel(app.NumberOfLayersLayout);
            app.NumberofLayersLabel_2 = uilabel(app.NumberOfLayersPanel);
            app.NumberofLayersLabel_2.Text = 'Number of Layers';
            app.NumLayerSpinner = uispinner(app.NumberOfLayersPanel);
            app.NumLayerSpinner.Limits = [1 Inf];
            app.NumLayerSpinner.ValueChangedFcn = createCallbackFcn(app, @NumLayerSpinnerValueChanged, true);
            app.NumLayerSpinner.Value = 1;
            % Show the figure after all components are created
            app.Module4CurvedLayerSlicingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module4GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Module4CurvedLayerSlicingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Module4CurvedLayerSlicingUIFigure)
        end
    end
end